classdef vrControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        trialTypesLinkedToEnvironment   matlab.ui.control.StateButton
        vrenvInUse                      matlab.ui.control.ListBox
        vrenvOptions                    matlab.ui.control.ListBox
        rewardSize                      matlab.ui.control.NumericEditField
        RewardSizeEditFieldLabel        matlab.ui.control.Label
        backwardsMovementSwitch         matlab.ui.control.CheckBox
        loadSettings                    matlab.ui.control.Button
        saveSettings                    matlab.ui.control.Button
        printrigparametersButton        matlab.ui.control.Button
        previewExperiment               matlab.ui.control.Button
        runExperiment                   matlab.ui.control.Button
        vrDirectory                     matlab.ui.control.EditField
        updateDirectory                 matlab.ui.control.Button
        vrExtension                     matlab.ui.control.EditField
        fileextensionforenvironmentsLabel  matlab.ui.control.Label
        vrDirectoryLabel                matlab.ui.control.Label
        animalName                      matlab.ui.control.EditField
        animalNameLabel                 matlab.ui.control.Label
        sessionOffset                   matlab.ui.control.NumericEditField
        sessionOffsetLabel              matlab.ui.control.Label
        maxTrialNumber                  matlab.ui.control.NumericEditField
        maxTrialNumberLabel             matlab.ui.control.Label
        maxTrialDuration                matlab.ui.control.NumericEditField
        maxTrialDurationLabel           matlab.ui.control.Label
        settingsName                    matlab.ui.control.EditField
        settingsNameLabel               matlab.ui.control.Label
        useTrainingMode                 matlab.ui.control.CheckBox
        lickEncoderAvailable            matlab.ui.control.CheckBox
        IntertrialTimingsPanel          matlab.ui.container.Panel
        minimumITI                      matlab.ui.control.NumericEditField
        minimumITILabel                 matlab.ui.control.Label
        randomizeITI                    matlab.ui.control.CheckBox
        randomMeanITI                   matlab.ui.control.NumericEditField
        randomMeanITILabel              matlab.ui.control.Label
        distributionITI                 matlab.ui.control.DropDown
        trialSettingsTabs               matlab.ui.container.TabGroup
        ConditionalTrialTypesTab        matlab.ui.container.Tab
        vrenvSelectionIndicatorConditional  matlab.ui.control.Label
        updatetrialtypeButton           matlab.ui.control.Button
        condTrialList                   matlab.ui.control.ListBox
        condProbRewSetting              matlab.ui.control.NumericEditField
        condProbRewSettingLabel         matlab.ui.control.Label
        condActiveLickSetting           matlab.ui.control.NumericEditField
        condActiveLickSettingLabel      matlab.ui.control.Label
        condActiveStopDuration          matlab.ui.control.NumericEditField
        condActiveStopDurationLabel     matlab.ui.control.Label
        condMvmtGainSetting             matlab.ui.control.NumericEditField
        condMvmtGainSettingLabel        matlab.ui.control.Label
        condFreqSetting                 matlab.ui.control.NumericEditField
        condFreqSettingLabel            matlab.ui.control.Label
        condProbRew                     matlab.ui.control.StateButton
        condActiveLick                  matlab.ui.control.StateButton
        condActiveStop                  matlab.ui.control.StateButton
        condMvmtGain                    matlab.ui.control.StateButton
        addnewButton                    matlab.ui.control.Button
        removeButton                    matlab.ui.control.Button
        IndependentPRActiveLickingStoppingMovementGainTab  matlab.ui.container.Tab
        vrenvSelectionIndicatorIndependent  matlab.ui.control.Label
        movementGain                    matlab.ui.control.NumericEditField
        movementGainLabel               matlab.ui.control.Label
        randGain                        matlab.ui.control.CheckBox
        minGain                         matlab.ui.control.NumericEditField
        minGainLabel                    matlab.ui.control.Label
        maxGain                         matlab.ui.control.NumericEditField
        maxGainLabel                    matlab.ui.control.Label
        randReward                      matlab.ui.control.CheckBox
        rewProbList                     matlab.ui.control.ListBox
        rewProbNewProb                  matlab.ui.control.NumericEditField
        rewProbNewProbLabel             matlab.ui.control.Label
        rewProbNewFreq                  matlab.ui.control.NumericEditField
        rewProbNewFreqLabel             matlab.ui.control.Label
        rewProbAddNew                   matlab.ui.control.Button
        rewProbRemove                   matlab.ui.control.Button
        activeLicking                   matlab.ui.control.CheckBox
        activeStopping                  matlab.ui.control.CheckBox
        stopDuration                    matlab.ui.control.NumericEditField
        stopDurationLabel               matlab.ui.control.Label
        messagestouserTextArea          matlab.ui.control.TextArea
        messagestouserTextAreaLabel     matlab.ui.control.Label
        EnvironmentBlockStructureLabel  matlab.ui.control.Label
        environmentBlockTab             matlab.ui.container.TabGroup
        InitialTab                      matlab.ui.container.Tab
        initActive                      matlab.ui.control.CheckBox
        initNumTrials                   matlab.ui.control.NumericEditField
        initNumTrialsLabel              matlab.ui.control.Label
        initEnvironment                 matlab.ui.control.DropDown
        initEnvironmentLabel            matlab.ui.control.Label
        PresetTab                       matlab.ui.container.Tab
        presetBlocks                    matlab.ui.control.CheckBox
        evenTrialsPerEnv                matlab.ui.control.StateButton
        envTrials                       matlab.ui.control.NumericEditField
        envTrialsLabel                  matlab.ui.control.Label
        presetTrialsList                matlab.ui.control.ListBox
        RandomTab                       matlab.ui.container.Tab
        randomBlocks                    matlab.ui.control.CheckBox
        evenRandMeanPerEnv              matlab.ui.control.StateButton
        randomDistribution              matlab.ui.control.DropDown
        randomLengthMean                matlab.ui.control.NumericEditField
        randomLengthMeanLabel           matlab.ui.control.Label
        randomMeanList                  matlab.ui.control.ListBox
        envSettings                     matlab.ui.control.DropDown
        envSettingsLabel                matlab.ui.control.Label
        envLength                       matlab.ui.control.NumericEditField
        envLengthLabel                  matlab.ui.control.Label
        dsFactor                        matlab.ui.control.NumericEditField
        dsFactorLabel                   matlab.ui.control.Label
        rewardPosition                  matlab.ui.control.NumericEditField
        rewardPositionLabel             matlab.ui.control.Label
        rewardTolerance                 matlab.ui.control.NumericEditField
        rewardToleranceLabel            matlab.ui.control.Label
        envOrder                        matlab.ui.control.DropDown
        envOrderLabel                   matlab.ui.control.Label
        envActive                       matlab.ui.control.StateButton
        printenvironmentinfoButton      matlab.ui.control.Button
        vrenvOptionsLabel               matlab.ui.control.Label
        vrenvInUseLabel                 matlab.ui.control.Label
        topBox                          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        userMessages = cell(0,2);
    end
    
    properties (Access = private)
        validDirectory = false; % boolean variable, flips when there's a valid vrDirectory
        environmentInfo = zeros(0,9); % #Frames, RewardPosition, RewardPosType, Order
        vrLength
        vrFrames
        vrFramesDS
        vrDSFactor
        vrRewPos
        vrRewTol
        vrOrder
        vrActive
        vrNumPresetTrials % Array of number of trials in block for each environment (length=numEnvActive, value=numTrialsPerEnvironment)
        vrNumRandTrials % Array of random mean of block length for each environment
        envBaseNames = cell(0); % base names for each environment (filename)
        envRichNames = cell(0); % rich names for each environment
        envOptionsLastClick = datetime("now"); % time of last click on vrEnv option (for double click)
        envOptionsLastSelection = nan; % index of last click on vrEnv option
        envInUseLastClick = datetime("now"); % time of last click on vrEnv in use
        envInUseLastSelection = nan; % index of last click on vrEnv in use
        doubleClickTime = 0.5; % Time elapsed to consider something a double click
        randRewardArray = [0, 1; 1, 1]; % numeric array of possible reward probabilities (first column P(rew), second Freq)
        rigInfo; % structure containing all rig information (loaded in startup with rigParameters()


        % These store the environment specific conditional trial properties
        % If the trialTypesLinkedToEnvironment is False, they aren't used
        % If set to true, these are linked to environment in the output
        % structure for the experiment protocol
        vrCondProbRew
        vrCondActiveLick
        vrCondActiveStop
        vrCondMvmtGain
        vrCondTrialReward
        vrCondTrialActiveLicking
        vrCondTrialActiveStopping
        vrCondTrialGain
        vrCondTrialFreq
        vrRandomReward
        vrRandomRewardArray
        vrActiveLicking
        vrActiveStopping
        vrActiveStopDuration
        vrRandomGain
        vrMovementGain
        vrMinGain
        vrMaxGain

        % These store the arrays of the trial type info for conditional
        % trial types
        condTrialReward = [1 1 0]; % Array containing P(rew) for conditional trials
        condTrialActiveLicking = [1 0 0]; % Array containing active licking mode for conditional trials
        condTrialActiveStopping = [0 0 0]; % Array containing active stopping mode for conditional trials
        condTrialGain = [1 1 1]; % Array containing movement gain for conditional trials
        condTrialFreq = [65 25 10]; % Array containing frequency for each conditional trial type
    end
    
    methods (Access = private)
        
        function validateVrDirectory(app,newDirectory,startupSwitch)
            figure(app.UIFigure);
            if nargin<3, startupSwitch = false; end
            if isempty(newDirectory), return; end
            if exist(newDirectory, 'dir')
                % Make sure vrDirectory ends in fileseparator
                if ~strcmp(newDirectory(end),filesep)
                    newDirectory(end+1) = filesep; 
                end
                app.validDirectory = true;
                app.vrDirectory.Value = persistentVariables('vrDirectory', newDirectory);
                activateEnvironmentOptions(app) % this activates all environment options from vrDirectory
                presentMessage(app, 'New vrDirectory selected, options reset throughout app.', 1)
            elseif startupSwitch
                app.validDirectory = false; 
                app.vrDirectory.Value = persistentVariables('vrDirectory', '');
            else
                app.validDirectory = false;
                app.vrDirectory.Value = '';
                clearEnvironmentOptions(app) % clear all environment options 
                % ATL: need to toggle linkTrialTypesToEnv
                presentMessage(app, 'Invalid vrDirectory requested, all options disabled.', 2)
            end
        end

        function activateEnvironmentOptions(app)
            if ~app.validDirectory
                presentMessage(app, 'can''t activate environment without valid directory', 2); 
                clearEnvironmentOptions(app)
                return
            end
            % Look for vr environment files and populate menu
            vrExt = standardizeExtension(app);
            vrList = dir(fullfile(app.vrDirectory.Value,['*',vrExt]));
            numEnv = length(vrList);

            % Build Environment Info Array
            app.vrLength = environmentDefaults(app,'length') * ones(numEnv,1);
            app.vrFrames = zeros(numEnv,1);
            app.vrFramesDS = zeros(numEnv,1);
            app.vrDSFactor = environmentDefaults(app,'dsfactor') * ones(numEnv,1);
            app.vrRewPos = environmentDefaults(app,'rewpos') * ones(numEnv,1);
            app.vrRewTol = environmentDefaults(app,'rewtol') * ones(numEnv,1);
            app.vrOrder = environmentDefaults(app,'order') * ones(numEnv,1);
            app.vrActive = environmentDefaults(app,'active') * ones(numEnv,1);
            app.vrNumPresetTrials = app.envTrials.Value * ones(numEnv,1);
            app.vrNumRandTrials = app.randomLengthMean.Value * ones(numEnv,1);
            for vrenv = 1:numEnv
                cpath = fullfile(app.vrDirectory.Value,vrList(vrenv).name);
                ctifinfo = imfinfo(cpath);
                numFrames = length(ctifinfo);
                app.vrFrames(vrenv) = numFrames;
                app.vrFramesDS(vrenv) = numFrames;
            end

            % Update UI
            app.vrenvOptions.Items = repmat({''},numEnv,1);
            app.vrenvOptions.ItemsData = 1:numEnv; 
            app.vrenvOptions.Value = {}; % deselect everything
            app.vrenvOptions.Enable = "on"; % enable selection
            deactivateVrEnvInUse(app);
            app.envSettings.Items = repmat({''},numEnv,1);
            app.envSettings.ItemsData = 1:numEnv;
            updateOrderMenu(app,0); % reset order menu
            for vrenv = 1:numEnv
                [~,cname] = fileparts(vrList(vrenv).name);
                app.envBaseNames{vrenv} = cname;
                app.envRichNames{vrenv} = constructRichName(app, vrenv);
                app.vrenvOptions.Items{vrenv} = sprintf('%d - %s',vrenv,cname);
                app.envSettings.Items{vrenv} = cname;
            end

            % Enable user interface for environment settings
            app.vrenvOptions.Enable = "on";
            app.vrenvOptionsLabel.Enable = "on";
            app.vrenvInUse.Enable = "on";
            app.vrenvInUseLabel.Enable = "on";
            app.envSettings.Enable = "on";
            app.envSettingsLabel.Enable = "on";
            app.envLength.Enable = "on";
            app.envLengthLabel.Enable = "on";
            app.dsFactor.Enable = "on";
            app.dsFactorLabel.Enable = "on";
            app.rewardPosition.Enable = "on";
            app.rewardPositionLabel.Enable = "on";
            app.rewardTolerance.Enable = "on";
            app.rewardToleranceLabel.Enable = "on";
            app.envOrder.Enable = "on";
            app.envOrderLabel.Enable = "on";
            app.envActive.Enable = "on";

            updateInitialBlockMenu(app)
            updatePresetTrialsList(app)
            updateRandomTrialsList(app)
        end


        function loadEnvironmentOptions(app)
            % When loading settings, this loads environment options to GUI
            % after loading all the settings data into the app
            numEnv = length(app.vrLength);
            activeEnvironments = any(app.vrActive); 
            
            if activeEnvironments
                app.vrenvOptions.Enable = "on";
                app.vrenvInUse.Enable = "on";
            else
                app.vrenvOptions.Enable = "off";
                app.vrenvInUse.Enable = "off";
            end

            % Update UI
            app.vrenvOptions.Items = repmat({''},numEnv,1);
            app.vrenvOptions.ItemsData = 1:numEnv; 
            app.vrenvOptions.Value = {}; % deselect everything
            app.vrenvInUse.Value = {}; % Deselect everything
            app.envSettings.Items = repmat({''},numEnv,1);
            app.envSettings.ItemsData = 1:numEnv;
            for vrenv = 1:numEnv
                cname = app.envBaseNames{vrenv};
                app.envRichNames{vrenv} = constructRichName(app, vrenv);
                app.vrenvOptions.Items{vrenv} = sprintf('(%i)- %s',vrenv,cname);
                app.envSettings.Items{vrenv} = cname;
            end
            if activeEnvironments
                idxActive = returnOrder(app.vrOrder, app.vrActive);
                app.vrenvInUse.Items = app.envRichNames(idxActive);
                app.vrenvInUse.ItemsData = idxActive;
            else
                deactivateVrEnvInUse(app);
            end
            updateOrderMenu(app, activeEnvironments);
            updateSettingsMenus(app, 1); % default to selecting first environment (out of options) from settings menu
        end

        function clearEnvironmentOptions(app)
            app.vrLength = [];
            app.vrFrames = [];
            app.vrFramesDS = [];
            app.vrDSFactor = [];
            app.vrRewPos = [];
            app.vrRewTol = [];
            app.vrOrder = [];
            app.vrActive = [];
            app.vrenvOptions.Items = {};
            app.vrenvOptions.ItemsData = [];
            app.vrenvOptions.Value = {}; % Deselect everything
            app.vrenvOptions.Enable = "off"; % Turn off until envs available
            deactivateVrEnvInUse(app);
            app.envSettings.Items = {'path not defined'};
            app.envSettings.ItemsData = nan;
            app.vrenvOptions.Enable = "off";
            app.envSettings.Enable = "off";
            app.envSettingsLabel.Enable = "off";
            app.envLength.Enable = "off";
            app.envLengthLabel.Enable = "off";
            app.envLength.Value = environmentDefaults(app, 'length');
            app.dsFactor.Enable = "off";
            app.dsFactorLabel.Enable = "off";
            app.dsFactor.Value = environmentDefaults(app, 'dsfactor');
            app.rewardPosition.Enable = "off";
            app.rewardPositionLabel.Enable = "off";
            app.rewardPosition.Value = environmentDefaults(app,'rewpos');
            app.rewardTolerance.Enable = "off";
            app.rewardToleranceLabel.Enable = "off";
            app.rewardTolerance.Value = environmentDefaults(app, 'rewtol');
            updateOrderMenu(app, 0)
            app.envOrder.Enable = "off";
            app.envOrderLabel.Enable = "off";
            app.envActive.Enable = "off";
            app.envActive.Value = false;
            updateInitialBlockMenu(app)
        end

        function richEnvName = constructRichName(app, envidx)
            baseName = app.envBaseNames{envidx};
            lengthString = sprintf('Length=%icm',app.vrLength(envidx));
            framesString = sprintf('%i frames -(ds)-> %i',app.vrFrames(envidx),app.vrFramesDS(envidx));
            rewardUnit = 'cm';
            rewardString = sprintf('Reward Position=%.1f +/- %.1f%s', app.vrRewPos(envidx), app.vrRewTol(envidx), rewardUnit);
            richEnvName = [sprintf('(%i)-',envidx),strjoin({baseName,lengthString,framesString,rewardString},' || ')];
        end
        
        function addEnvironment(app,envidx)
            % after environment is double clicked on the options column,
            % this function adds it to the inUse column (if not already)
            if ismember(envidx, app.vrenvInUse.ItemsData)
                presentMessage(app, sprintf('Environment %d is already in use!',envidx),1)
                return
            end
            if any(app.vrActive)
                newIdx = length(app.vrenvInUse.Items) + 1;
            else
                newIdx = 1;
            end
            app.vrOrder(envidx) = newIdx;
            app.vrActive(envidx) = 1;
            updateOrderMenu(app,1);
            app.envRichNames{envidx} = constructRichName(app,envidx);
            app.vrenvInUse.Items{newIdx} = app.envRichNames{envidx};
            app.vrenvInUse.ItemsData(newIdx) = envidx;
            updateInitialBlockMenu(app); 
            updateSettingsMenus(app,envidx); % update settings menus and enable
            presentMessage(app, sprintf('Environment %d now in use.',envidx),1)
        end

        function removeEnvironment(app,envidx)
            % after environment is double clicked on inUse column or
            % envActive toggled, remove environment from everything
            if ~ismember(envidx, app.vrenvInUse.ItemsData)
                error('Environment (%d) is being removed but it is not currently active',envidx); 
            end
            app.vrOrder(envidx) = environmentDefaults(app,'order');
            app.vrActive(envidx) = false;
            updateOrderMenu(app,1);

            activeEnvironments = any(app.vrActive); % reset if last environment
            app.envRichNames{envidx} = constructRichName(app,envidx); % update it's rich name
            if activeEnvironments
                % If there remain active environments, then:
                % Reset order of remaining environments
                idxActive = find(app.vrActive);
                pOrder = app.vrOrder(idxActive);
                [~,pOrdIdx] = sort(pOrder);
                app.vrOrder(idxActive) = pOrdIdx;

                % Repopulate vrenvInUse array with correct order
                app.vrenvInUse.Items = {};
                app.vrenvInUse.ItemsData = [];
                for eidx = 1:length(idxActive)
                    cenv = idxActive(pOrdIdx(eidx));
                    app.envRichNames{cenv} = constructRichName(app,cenv);
                    app.vrenvInUse.Items{eidx} = app.envRichNames{cenv};
                    app.vrenvInUse.ItemsData(eidx) = cenv;
                end
            else
                % Otherwise, clear vrenvInUse section
                app.vrenvInUse.Items = {};
                app.vrenvInUse.ItemsData = [];
            end
            updateInitialBlockMenu(app); 
            updateSettingsMenus(app,envidx); % update settings menus and enable
            presentMessage(app, sprintf('Environment %d is no longer in use.',envidx),1)
        end
        
        function updateSettingsMenus(app,envidx)
            % - length(1)
            % - frames(2) / dsFrames(3) /dsFactor(4)
            % - rewardPosition(5) / rewardTolerance(6) / rewardUnits(7)
            % - order in block(8)
            % - active(9)
            % -- code for updating all the settings menus when the dropdown
            % value changes or when a new environment is selected --
            if app.vrActive(envidx)
                enableString = "on";
                app.envSettings.Value = envidx;
                app.envLength.Value = app.vrLength(envidx);
                app.dsFactor.Value = app.vrDSFactor(envidx);
                app.rewardPosition.Value = 0;
                app.rewardTolerance.Value = 0;
                app.rewardPosition.Limits = [0 app.vrLength(envidx)];
                app.rewardTolerance.Limits = [0 app.vrLength(envidx)];
                app.rewardPosition.Value = app.vrRewPos(envidx);
                app.rewardTolerance.Value = app.vrRewTol(envidx);
                updateOrderMenu(app,1)
                app.envOrder.Value = app.vrOrder(envidx);
                app.envActive.Value = app.vrActive(envidx);
            else
                enableString = "off";
                app.envSettings.Value = envidx;
                app.envLength.Value = environmentDefaults(app,'length'); % default
                app.dsFactor.Value = environmentDefaults(app,'dsfactor');
                app.rewardPosition.Value = 0;
                app.rewardTolerance.Value = 0;
                app.rewardPosition.Limits = [0 environmentDefaults(app,'length')];
                app.rewardTolerance.Limits = [0 environmentDefaults(app,'length')];                    
                app.rewardPosition.Value = environmentDefaults(app,'rewpos');
                app.rewardTolerance.Value = environmentDefaults(app,'rewtol');
                updateOrderMenu(app,0)
                app.envOrder.Value = environmentDefaults(app,'order');
                app.envActive.Value = environmentDefaults(app,'active');
            end
            if app.trialTypesLinkedToEnvironment.Value
                app.randReward.Value = app.vrRandomReward{envidx};
                app.randRewardArray = app.vrRandomRewardArray{envidx};
                app.activeLicking.Value = app.vrActiveLicking{envidx};
                app.activeStopping.Value = app.vrActiveStopping{envidx};
                app.stopDuration.Value = app.vrActiveStopDuration{envidx};
                app.movementGain.Value = app.vrMovementGain{envidx};
                app.minGain.Value = app.vrMinGain{envidx};
                app.maxGain.Value = app.vrMaxGain{envidx};
                app.condProbRew.Value = app.vrCondProbRew{envidx};
                app.condActiveLick.Value = app.vrCondActiveLick{envidx};
                app.condActiveStop.Value = app.vrCondActiveStop{envidx};
                app.condMvmtGain.Value = app.vrCondMvmtGain{envidx};
                app.condTrialReward = app.vrCondTrialReward{envidx};
                app.condTrialActiveLicking = app.vrCondTrialActiveLicking{envidx};
                app.condTrialActiveStopping = app.vrCondTrialActiveStopping{envidx};
                app.condTrialGain = app.vrCondTrialGain{envidx};
                app.condTrialFreq = app.vrCondTrialFreq{envidx};
                updateConditionalStructure(app)
                handleChangeToConditionalTrials(app)
                generateRewardProbList(app)
                message = sprintf("trial types for environment %s", app.envBaseNames{envidx});
                app.vrenvSelectionIndicatorIndependent.Text = message;
                app.vrenvSelectionIndicatorConditional.Text = message;
            end
            app.envSettings.Enable = enableString;
            app.envSettingsLabel.Enable = enableString;
            app.envLength.Enable = enableString;
            app.envLengthLabel.Enable = enableString;
            app.dsFactor.Enable = enableString;
            app.dsFactorLabel.Enable = enableString;
            app.rewardPosition.Enable = enableString;
            app.rewardPositionLabel.Enable = enableString;
            app.rewardTolerance.Enable = enableString;
            app.rewardToleranceLabel.Enable = enableString;
            app.envOrder.Enable = enableString;
            app.envOrderLabel.Enable = enableString;
            app.envActive.Enable = enableString;
        end

        function vrExtension = standardizeExtension(app)
            % This makes sure that the extension has a leading period
            vrExtension = app.vrExtension.Value;
            if vrExtension(1)~='.'
                vrExtension = ['.',vrExtension]; 
            end
        end

        function changeBlockStructure(app, newType)
            % This is called every time a different block structure is
            % selected. It enables all settings within the current block
            % structure and disenables all settings from other block
            % structures.
            
            if strcmpi(newType, 'preset')
                % If we've switched to the Even Block Structure --
            
                % Enable even block settings
                app.PresetTab.Title = '*Preset*';
                app.presetBlocks.Value = true;
                app.presetBlocks.Enable = "off";
                app.envTrials.Enable = "on";
                app.envTrialsLabel.Enable = "on";
                app.presetTrialsList.Enable = "on";
                app.evenTrialsPerEnv.Enable = "on";
                
                % Disenable random block settings
                app.RandomTab.Title = 'Random';
                app.randomBlocks.Value = false; 
                app.randomBlocks.Enable = "on";
                app.randomLengthMean.Enable = "off";
                app.randomLengthMeanLabel.Enable = "off";
                app.randomDistribution.Enable = "off";            
                app.randomMeanList.Enable = "off";
                app.evenRandMeanPerEnv.Enable = "off";
                
            elseif strcmpi(newType, 'random')
                % If we've switched to the Random Block Structure --

                % Enable even block settings
                app.PresetTab.Title = 'Preset';
                app.presetBlocks.Value = false;
                app.presetBlocks.Enable = "on";
                app.envTrials.Enable = "off";
                app.envTrialsLabel.Enable = "off";
                app.presetTrialsList.Enable = "off";
                app.evenTrialsPerEnv.Enable = "off";
                
                % Disenable random block settings
                app.RandomTab.Title = '*Random*';
                app.randomBlocks.Value = true; 
                app.randomBlocks.Enable = "off"; 
                app.randomLengthMean.Enable = "on";
                app.randomLengthMeanLabel.Enable = "on";
                app.randomDistribution.Enable = "on";
                app.randomMeanList.Enable = "on";
                app.evenRandMeanPerEnv.Enable = "on";
                
            else
                error('changeBlockStructure received unknown type!');
            end

            presentMessage(app, sprintf('Block structure changed to %s.',newType), 1);
        end
        
        function presentMessage(app,message,type)
            app.messagestouserTextArea.Value = message;
            if type==1, app.messagestouserTextArea.FontColor = 'k'; end
            if type==2, app.messagestouserTextArea.FontColor = 'r'; end
            messageNumber = size(app.userMessages,1) + 1;
            app.userMessages{messageNumber,1} = message;
            app.userMessages{messageNumber,2} = type;
        end
        
        function envSettingsChanged(app,envidx)
            % Construct rich environment name again
            app.envRichNames{envidx} = constructRichName(app,envidx);
            % If environment is active, then it should be in the inuseList
            % and we need to update the string associated with it
            if app.vrActive(envidx)
                app.vrenvInUse.Items{app.vrOrder(envidx)} = app.envRichNames{envidx};
            end
            
        end
        
        function value = environmentDefaults(~,feature)
            switch lower(feature)
                case 'length', value = 250; % 250cm default length
                case 'dsfactor', value = 1; % default ds factor = no downsampling
                case 'rewpos', value = 200; % in cm
                case 'rewtol', value = 10; % in cm
                case 'rewunit', value = 2; % 1=%, 2=cm
                case 'order', value = -1;
                case 'active', value = 0;
                case 'abslimits', value = [0 2000];
                case 'perlimits', value = [0 100];
                otherwise, error('feature string not recognized!!');
            end            
        end
        
        function updateOrderMenu(app,setting)
            numActive = sum(app.vrActive);
            if logical(setting) && numActive
                app.envOrder.Items = cellfun(@(idx) sprintf('%i',idx), num2cell(1:numActive), 'uni', 0);
                app.envOrder.ItemsData = 1:numActive;
            else
                app.envOrder.Items = {'n/a'};
                app.envOrder.ItemsData = -1;
            end
        end
        
        function updateInitialBlockMenu(app)
            idxActive = find(app.vrActive);
            pValue = app.initEnvironment.Value; % Store previous value
            if ~ismember(app.initEnvironment.Value, idxActive)
                app.initActive.Value = false; 
            end
            if ~isempty(idxActive)
                app.initEnvironment.Items = cellfun(@(eidx) sprintf('%i',eidx), num2cell(idxActive), 'uni', 0);
                app.initEnvironment.ItemsData = idxActive;
            else
                app.initEnvironment.Items = {'n/a'};
                app.initEnvironment.ItemsData = -1;
                app.initEnvironment.Value = -1;
                app.initActive.Value = false;
            end
            if ismember(pValue, idxActive)
                app.initEnvironment.Value = pValue; 
            end

            if app.initNumTrials.Value==0
                app.initActive.Value = false; 
            end

            if app.initActive.Value
                app.InitialTab.Title = '*Initial*';
            else
                app.InitialTab.Title = 'Initial';
            end
        end
        
        function generateRewardProbList(app)
            rewProb = app.randRewardArray(:,1);
            rewFreq = app.randRewardArray(:,2);
            rewPerc = rewFreq / sum(rewFreq) * 100;
            numRew = size(rewProb,1);
            app.rewProbList.Items = repmat({''}, numRew, 1);
            app.rewProbList.ItemsData = 1:numRew;
            for rew = 1:numRew
                rewString = sprintf('P(R)=%.2f, %.1f%%, F=%d',rewProb(rew),rewPerc(rew),rewFreq(rew));
                app.rewProbList.Items{rew} = rewString;
            end
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrRandomRewardArray", app.randRewardArray)
            end
        end

        function out = generateOutputStructure(app)
            % Meta Parameters
            out.vrDirectory = app.vrDirectory.Value; 
            out.vrExtension = app.vrExtension.Value;
            out.animalName = app.animalName.Value; 
            out.sessionOffset = app.sessionOffset.Value; 
            out.maxTrialNumber = app.maxTrialNumber.Value;
            out.maxTrialDuration = app.maxTrialDuration.Value; 
            out.settingsName = app.settingsName.Value;
            out.useTrainingMode = app.useTrainingMode.Value;
            out.rewardSize = app.rewardSize.Value;
            
            % VR Directory, Options, & In Use + Settings
            idxActive = returnOrder(app.vrOrder, app.vrActive);
            out.vrOptions = app.envBaseNames;
            out.vrInUse = app.envBaseNames(idxActive); % Names of environments in use in order
            out.vrLength = app.vrLength;
            out.vrFrames = app.vrFrames;
            out.vrFramesDS = app.vrFramesDS;
            out.vrDSFactor = app.vrDSFactor;
            out.vrRewPos = app.vrRewPos;
            out.vrRewTol = app.vrRewTol;
            out.vrOrder = app.vrOrder;
            out.vrActive = app.vrActive;
            
            % Intertrial Interval Settings
            out.minimumITI = app.minimumITI.Value; 
            out.randomITI = app.randomizeITI.Value; 
            if out.randomITI
                out.randomMeanITI = app.randomMeanITI.Value; 
                out.distributionITI = app.distributionITI.Value;
                out.distributionNameITI = app.distributionITI.Items{app.distributionITI.Value};
            else
                out.randomMeanITI = []; 
                out.distributionITI = [];
                out.distributionNameITI = '';
            end

            % Backwards Movement control
            out.preventBackwardMovement = app.backwardsMovementSwitch.Value;
            
            % Trial Structure Settings
            out.trialTypesLinkedToEnvironment = app.trialTypesLinkedToEnvironment.Value;

            if out.trialTypesLinkedToEnvironment
                out.vrCondProbRew = app.vrCondProbRew;
                out.vrCondActiveLick = app.vrCondActiveLick;
                out.vrCondActiveStop = app.vrCondActiveStop;
                out.vrCondMvmtGain = app.vrCondMvmtGain;
                out.vrCondTrialReward = app.vrCondTrialReward;
                out.vrCondTrialActiveLicking = app.vrCondTrialActiveLicking;
                out.vrCondTrialActiveStopping = app.vrCondTrialActiveStopping;
                out.vrCondTrialGain = app.vrCondTrialGain;
                out.vrCondTrialFreq = app.vrCondTrialFreq;
                out.vrRandomReward = app.vrRandomReward;
                out.vrRandomRewardArray = app.vrRandomRewardArray;
                out.vrActiveLicking = app.vrActiveLicking;
                out.vrActiveStopping = app.vrActiveStopping;
                out.vrActiveStopDuration = app.vrActiveStopDuration;
                out.vrRandomGain = app.vrRandomGain;
                out.vrMovementGain = app.vrMovementGain;
                out.vrMinGain = app.vrMinGain;
                out.vrMaxGain = app.vrMaxGain;
            else
                % Trial Structure Rewards
                out.condReward = app.condProbRew.Value;
                out.condTrialReward = app.condTrialReward;
                if out.condReward
                    out.randomReward = false;
                    out.randomRewardArray = [];
                else
                    out.randomReward = app.randReward.Value; 
                    if out.randomReward
                        out.randomRewardArray = app.randRewardArray;
                    else
                        out.randomRewardArray = [];
                    end
                end
                
                % Trial Structure Active Licking
                % Multiply by whether lick encoder is available to ignore
                % settings if it isn't...
                out.condActiveLicking = app.condActiveLick.Value * app.lickEncoderAvailable.Value;
                out.condTrialActiveLicking = app.condTrialActiveLicking * app.lickEncoderAvailable.Value; 
                out.activeLicking = app.activeLicking.Value * app.lickEncoderAvailable.Value;
                
                % Handle Active Stopping
                out.condActiveStopping = app.condActiveStop.Value;
                out.condTrialActiveStopping = app.condTrialActiveStopping;
                out.activeStopping = app.activeStopping.Value;
                out.stopDuration = app.stopDuration.Value;
    
                % Trial Structure Movement Gain
                out.condGain = app.condMvmtGain.Value;
                out.condTrialGain = app.condTrialGain;
                if out.condGain
                    out.movementGain = [];
                    out.randomGain = false;
                    out.minGain = [];
                    out.maxGain = [];
                else
                    out.movementGain = app.movementGain.Value;
                    out.randomGain = app.randGain.Value;
                    if out.randomGain
                        out.minGain = app.minGain.Value;
                        out.maxGain = app.maxGain.Value;
                    else
                        out.minGain = [];
                        out.maxGain = [];
                    end
                end
                
                % Trial Structure frequency of conditional trial types
                out.condTrialFreq = app.condTrialFreq;
            end

            % Initial Block Settings
            out.initBlock = app.initActive.Value; 
            if out.initBlock
                out.initTrials = app.initNumTrials.Value;
                out.initEnvIdx = app.initEnvironment.Value; 
                out.initEnvName = app.envBaseNames{out.initEnvIdx};
            else
                out.initTrials = 0;
                out.initEnvIdx = []; 
                out.initEnvName = '';
            end

            % Block Structure Settings
            out.blockTypeNames = {'Preset','Random'};
            out.blockType = [app.presetBlocks.Value, app.randomBlocks.Value];
            if sum(out.blockType)~=1, error('Somehow the block type wasn''t clear. Multiple were selected.'); end
            % Initialize all possibilities, then set whatever is relevant
            out.blockTrialsPer = []; 
            out.evenTrialsPerEnv = [];
            out.blockRandomMean = [];
            out.evenRandMeanPerEnv = [];
            out.blockRandomDistribution = [];
            out.blockRandomDistributionName = '';
            if out.blockType(1)
                out.blockTrialsPer = app.vrNumPresetTrials; % List of num trials per environment in even blocks
                out.evenTrialsPerEnv = app.evenTrialsPerEnv.Value; % Toggle for whether we have even trial length blocks
            end
            if out.blockType(2)
                out.blockRandomMean = app.vrNumRandTrials; % List of random trial mean per environment in random blocks
                out.evenRandMeanPerEnv = app.evenRandMeanPerEnv.Value; % Toggle for whether random mean is same for each environment
                out.blockRandomDistribution = app.randomDistribution.Value;
                out.blockRandomDistributionName = app.randomDistribution.Items{app.randomDistribution.Value};
            end
        end
        
        function highlightComponent(app, component, property)
            if nargin < 3, property = 'BackgroundColor'; end
            if ~iscell(component), component = {component}; end
            numComponent = length(component);
            for nc = 1:numComponent
                cComponent = component{nc};
                if ~any(strcmp(fields(app),cComponent)), error('Requested component (%s) not a field of the app!', component{nc}); end
                if ~any(strcmp(fields(app.(cComponent)),property)), error('Requested component (%s) doesn''t have a %s!',component{nc}, property); end
            end
            timing = 0.05; % How long to wait before each flip
            numFlips = 3; % Number of flips to red
            gentleRed = [1,0.8,0.8]; % Red color to flip background to
            highlightColors = repmat({gentleRed},numComponent,2);
            for nc = 1:numComponent
                % Store previous color
                highlightColors{nc,1} = app.(component{nc}).(property);
            end
            for idx = 2:2*numFlips+1
                % Do flips
                for nc = 1:numComponent
                    app.(component{nc}).(property) = highlightColors{nc,mod(idx-1,2)+1};
                end
                pause(timing);
            end
        end

        function updateConditionalStructure(app)
            numTrialTypes = length(app.condTrialReward);
            if length(app.condTrialActiveLicking) ~= numTrialTypes || length(app.condTrialGain) ~= numTrialTypes || length(app.condTrialFreq) ~= numTrialTypes
                error('Conditional trial vectors don''t have same length...');
            end

            condRewardOn = app.condProbRew.Value;
            condActLickOn = app.condActiveLick.Value;
            condActStopOn = app.condActiveStop.Value;
            condGainOn = app.condMvmtGain.Value;
            if condRewardOn || condActLickOn || condActStopOn || condGainOn
                app.ConditionalTrialTypesTab.Title = '*Conditional Trial Types*';
            else
                app.ConditionalTrialTypesTab.Title = 'Conditional Trial Types';
            end

            if condRewardOn
                % Then turn off the independent control of p(reward)
                enableString = "off";
                app.rewProbNewFreq.Enable = enableString;
                app.rewProbNewProb.Enable = enableString;
                app.rewProbRemove.Enable = enableString;
                app.rewProbAddNew.Enable = enableString;
                app.rewProbNewFreqLabel.Enable = enableString;
                app.rewProbNewProbLabel.Enable = enableString;
                app.rewProbList.Enable = enableString;
                app.randReward.Enable = enableString;
            else
                % Then turn on independent control of p(reward)
                app.randReward.Enable = "on";
                randRewardValueChanged(app)
            end

            if condActLickOn
                % Turn off independent control of activeLicking
                app.activeLicking.Enable = "off";
            else
                % Turn on independent control of activeLicking
                app.activeLicking.Enable = "on";
            end
            
            if condActStopOn
                % Turn off independent control of activeStopping
                app.activeStopping.Enable = "off";
                app.stopDuration.Enable = "off";
                app.stopDurationLabel.Enable = "off";
            else
                % Turn on independent control of activeStopping
                app.activeStopping.Enable = "on";
                app.stopDuration.Enable = "on";
                app.stopDurationLabel.Enable = "on";
            end

            if condGainOn
                % Turn off independent control of movement gain
                enableString = "off";
                app.movementGain.Enable = enableString;
                app.movementGainLabel.Enable = enableString;
                app.maxGain.Enable = enableString;
                app.maxGainLabel.Enable = enableString;
                app.minGain.Enable = enableString;
                app.minGainLabel.Enable = enableString;
                app.randGain.Enable = enableString;
            else
                % Turn on independent control of movement gain
                app.randGain.Enable = "on";
                app.movementGain.Enable = "on";
                app.movementGainLabel.Enable = "on";
                randGainValueChanged(app)
            end
            handleChangeToConditionalTrials(app)
        end
        
        function handleChangeToConditionalTrials(app)
            generateCondTrialList(app)
            condTrialListValueChanged(app)
            updateVrenvLinkedCondTrialValues(app)
        end

        function updateVrenvLinkedCondTrialValues(app)
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrCondProbRew", app.condProbRew.Value)
                updateVrenvTrialType(app, "vrCondActiveLick", app.condActiveLick.Value)
                updateVrenvTrialType(app, "vrCondActiveStop", app.condActiveStop.Value)
                updateVrenvTrialType(app, "vrCondMvmtGain", app.condMvmtGain.Value)
                updateVrenvTrialType(app, "vrCondTrialReward", app.condTrialReward)
                updateVrenvTrialType(app, "vrCondTrialActiveLicking", app.condTrialActiveLicking)
                updateVrenvTrialType(app, "vrCondTrialActiveStopping", app.condTrialActiveStopping)
                updateVrenvTrialType(app, "vrCondTrialGain", app.condTrialGain)
                updateVrenvTrialType(app, "vrCondTrialFreq", app.condTrialFreq)
            end
        end

        function generateCondTrialList(app)
            condRewardOn = app.condProbRew.Value;
            condActLickOn = app.condActiveLick.Value;
            condActStopOn = app.condActiveStop.Value;
            condGainOn = app.condMvmtGain.Value;
            
            if condRewardOn || condActLickOn || condActStopOn || condGainOn

                condPerc = app.condTrialFreq / sum(app.condTrialFreq) * 100; 
                numCond = length(app.condTrialReward);
                app.condTrialList.Items = repmat({''}, numCond, 1);
                app.condTrialList.ItemsData = 1:numCond;
                for cond = 1:numCond
                    if condRewardOn
                        rewString = sprintf('P(R)=%.2f',app.condTrialReward(cond));
                        if condActLickOn || condActStopOn || condGainOn, rewPost = ', '; else, rewPost = ''; end
                    else
                        rewString = '';
                        rewPost = '';
                    end
                    if condActLickOn
                        actLickString = sprintf('AL=%d',app.condTrialActiveLicking(cond));
                        if condActStopOn || condGainOn, actLickPost = ', '; else, actLickPost = ''; end
                    else
                        actLickString = '';
                        actLickPost = '';
                    end
                    if condActStopOn
                        actStopString = sprintf('AS=%d',app.condTrialActiveStopping(cond));
                        if condGainOn, actStopPost = ', '; else, actStopPost = ''; end
                    else
                        actStopString = '';
                        actStopPost = '';
                    end
                    if condGainOn
                        gainString = sprintf('MG=%.2f',app.condTrialGain(cond));
                    else
                        gainString = sprintf('');
                    end
                    fullString = sprintf('%s%s%s%s%s%s%s, %.1f%%, F=%d',...
                        rewString,rewPost,actLickString,actLickPost,actStopString,actStopPost,gainString,condPerc(cond),app.condTrialFreq(cond));
                    app.condTrialList.Items{cond} = fullString;
                end
            else
                app.condTrialList.Items = {'conditional trial types off'};
                app.condTrialList.ItemsData = 1;
            end
        end
        
        function updatePresetTrialsList(app)
            numEnv = length(app.vrLength);
            app.presetTrialsList.Items = repmat({''},numEnv,1);
            app.presetTrialsList.ItemsData = 1:numEnv;
            if isempty(app.presetTrialsList.Value)
                app.presetTrialsList.Value = 1;
            end
            cindex = app.presetTrialsList.Value; 
            app.envTrials.Value = app.vrNumPresetTrials(cindex);
            for vrenv = 1:numEnv
                cEnvString = sprintf('%i - %s - #Trials:%d',vrenv, app.envBaseNames{vrenv}, app.vrNumPresetTrials(vrenv));
                app.presetTrialsList.Items{vrenv} = cEnvString;
            end
        end

        function updateRandomTrialsList(app)
            numEnv = length(app.vrLength);
            app.randomMeanList.Items = repmat({''},numEnv,1);
            app.randomMeanList.ItemsData = 1:numEnv;
            if isempty(app.randomMeanList.Value)
                app.randomMeanList.Value = 1;
            end
            cindex = app.randomMeanList.Value; 
            app.randomLengthMean.Value = app.vrNumRandTrials(cindex);
            for vrenv = 1:numEnv
                cEnvString = sprintf('%i - %s - #Trials:%d',vrenv, app.envBaseNames{vrenv}, app.vrNumRandTrials(vrenv));
                app.randomMeanList.Items{vrenv} = cEnvString;
            end
        end
        
        function deactivateVrEnvInUse(app)
            % This is called whenever we don't have any env in use options
            app.vrenvInUse.Items = {};
            app.vrenvInUse.ItemsData = [];
            app.vrenvInUse.Value = {}; % Deselect everything
            app.vrenvInUse.Enable = "off"; % Turn off until envs available
        end
        
        function switchToLinkingTrialsToEnvs(app)
            % This is called whenever we are switching modes between having
            % trial types independent of vrEnvs or conditional on them
            conditional = app.trialTypesLinkedToEnvironment.Value;
            numEnvOptions = length(app.vrenvOptions.Items);
            
            if conditional && numEnvOptions
                % Then we make trial types conditional on vrEnv
                % Make a cell array of repeated arrays of whatever is in
                % the trial types variables, one for each environment
                app.vrCondProbRew = repmat({app.condProbRew.Value}, numEnvOptions, 1);
                app.vrCondActiveLick = repmat({app.condActiveLick.Value}, numEnvOptions, 1);
                app.vrCondActiveStop = repmat({app.condActiveStop.Value}, numEnvOptions, 1);
                app.vrCondMvmtGain = repmat({app.condMvmtGain.Value}, numEnvOptions, 1);
                app.vrCondTrialReward = repmat({app.condTrialReward}, numEnvOptions, 1);
                app.vrCondTrialActiveLicking = repmat({app.condTrialActiveLicking}, numEnvOptions, 1);
                app.vrCondTrialActiveStopping = repmat({app.condTrialActiveStopping}, numEnvOptions, 1);
                app.vrCondTrialGain = repmat({app.condTrialGain}, numEnvOptions, 1);
                app.vrCondTrialFreq = repmat({app.condTrialFreq}, numEnvOptions, 1);
                app.vrRandomReward = repmat({app.randReward.Value}, numEnvOptions, 1);
                app.vrRandomRewardArray = repmat({app.randRewardArray}, numEnvOptions, 1);
                app.vrActiveLicking = repmat({app.activeLicking.Value}, numEnvOptions, 1);
                app.vrActiveStopping = repmat({app.activeStopping.Value}, numEnvOptions, 1);
                app.vrActiveStopDuration = repmat({app.stopDuration.Value}, numEnvOptions, 1);
                app.vrRandomGain = repmat({app.randGain.Value}, numEnvOptions, 1);
                app.vrMovementGain = repmat({app.movementGain.Value}, numEnvOptions, 1);
                app.vrMinGain = repmat({app.minGain.Value}, numEnvOptions, 1);
                app.vrMaxGain = repmat({app.maxGain.Value}, numEnvOptions, 1);
                envidx = app.envSettings.Value;
                updateSettingsMenus(app, envidx);

            elseif conditional && ~numEnvOptions
                % Fail safe to make sure this never happens (usually caught
                % earlier)
                app.trialTypesLinkedToEnvironment.Value = false;

            else
                % Clear these - we don't use them when trial types
                % independent of vrEnv
                app.vrCondProbRew = [];
                app.vrCondActiveLick = [];
                app.vrCondActiveStop = [];
                app.vrCondMvmtGain = [];
                app.vrCondTrialReward = [];
                app.vrCondTrialActiveLicking = [];
                app.vrCondTrialActiveStopping = [];
                app.vrCondTrialGain = [];
                app.vrCondTrialFreq = [];
                app.vrRandomReward = [];
                app.vrRandomRewardArray = [];
                app.vrActiveLicking = [];
                app.vrActiveStopping = [];
                app.vrActiveStopDuration = [];
                app.vrRandomGain = [];
                app.vrMovementGain = [];
                app.vrMinGain = [];
                app.vrMaxGain = [];
            end

            % Update message to reflect status
            if app.trialTypesLinkedToEnvironment.Value
                app.trialTypesLinkedToEnvironment.Text = "link trial types to environment (enabled!)";
            else
                app.trialTypesLinkedToEnvironment.Text = "link trial types to environment (disabled)";
                app.vrenvSelectionIndicatorIndependent.Text = "trial types are currently independent of VR environment";
                app.vrenvSelectionIndicatorConditional.Text = "trial types are currently independent of VR environment";
            end
        end
        
        function updateVrenvTrialType(app, componentName, newValue)
            if ~app.trialTypesLinkedToEnvironment.Value
                warning("Should never be attempting to updateVrenvTrialType when it isn't linked to vrEnv!")
                return
            end
            % Get the index of the environment currently selected
            idx = app.envSettings.Value;

            % Set the relevant component to the new value for the selected
            % environments data
            app.(componentName){idx} = newValue;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rigInfo = rigParameters();
            app.UIFigure.Position([1 2]) = app.rigInfo.localFigurePosition; 
            app.lickEncoderAvailable.Value = app.rigInfo.lickEncoderAvailable; 
            app.rewardSize.Value = app.rigInfo.waterVolumeBASE;

            % Do this upon startup to make sure it's consistent
            generateRewardProbList(app)
            handleChangeToConditionalTrials(app)
            validateVrDirectory(app, persistentVariables('vrDirectory'), true);
            app.animalName.Value = persistentVariables('animalName');
            app.settingsName.Value = persistentVariables('settingsName');
            app.useTrainingMode.Value = persistentVariables('useTrainingMode');
            loadSettingsButtonPushed(app)
        end

        % Value changed function: vrDirectory
        function vrDirectoryValueChanged(app, event)
            validateVrDirectory(app, app.vrDirectory.Value)
        end

        % Button pushed function: updateDirectory
        function updateDirectoryPushed(app, event)
            validateVrDirectory(app, uigetdir())
        end

        % Value changed function: vrExtension
        function vrExtensionValueChanged(app, event)
            genericExtensionSwitch = false;
            if genericExtensionSwitch
                app.vrExtension.Value = standardizeExtension(app); %#ok -- see string in presentMessage() in below else statement
                [~,~,fext] = fileparts(app.vrExtension.Value);
                if ~strcmp(fext, app.vrExtension.Value)
                    presentMessage(app,'Must provide valid file extension, reverting to default!',2);
                    app.vrExtension.Value = '.tif';
                    vrExtensionValueChanged(app,event) % Run again for certainty...
                end
            else
                app.vrExtension.Value = '.tif';
                presentMessage(app,'The only valid extension is .tif, will update to make generic in the future!', 1);
            end
        end

        % Value changed function: maxTrialNumber
        function maxTrialNumberValueChanged(app, event)
            app.initNumTrials.Limits = [0, app.maxTrialNumber.Value];
        end

        % Value changed function: envActive
        function envActiveValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrActive(envidx) = app.envActive.Value;
            if app.vrActive(envidx)
                addEnvironment(app,envidx);
            else
                removeEnvironment(app,envidx);
            end
        end

        % Value changed function: vrenvOptions
        function vrenvOptionsValueChanged(app, event)
            clickTime = datetime("now"); % Time of selection
            selection = event.Value; % New list of values (should be a numeric index)
            
            % If same option selected and double click, flip value
            secondsSinceLast = seconds(clickTime - app.envOptionsLastClick);
            wasDoubleClick = secondsSinceLast < app.doubleClickTime;
            if selection==app.envOptionsLastSelection && wasDoubleClick
                addEnvironment(app,selection); % add environment to inUse listbox
                updateSettingsMenus(app,selection); % select environment settings
            end

            % Deselect everything by default
            app.vrenvOptions.Value = {}; 
            
            % Update Double Click Parameters
            app.envOptionsLastClick = clickTime;
            app.envOptionsLastSelection = selection;
        end

        % Value changed function: vrenvInUse
        function vrenvInUseValueChanged(app, event)
            clickTime = datetime("now"); % Time of selection
            selection = event.Value; % New list of values (should be a numeric index)
            updateSettingsMenus(app,selection)
            
            % If same option selected and double click, flip value
            secondsSinceLast = seconds(clickTime - app.envInUseLastClick);
            wasDoubleClick = secondsSinceLast < app.doubleClickTime;
            if selection==app.envInUseLastSelection && wasDoubleClick
                removeEnvironment(app,selection); % add environment to inUse listbox
            end

            % Deselect everything by default
            app.vrenvInUse.Value = {}; 
            
            % Update Double Click Parameters
            app.envInUseLastClick = clickTime;
            app.envInUseLastSelection = selection;            
        end

        % Value changed function: envSettings
        function envSettingsValueChanged(app, event)
            updateSettingsMenus(app, event.Value);            
        end

        % Value changed function: envLength
        function envLengthValueChanged(app, event)
            envidx = app.envSettings.Value;
            pLength = app.vrLength(envidx);
            pPosition = app.vrRewPos(envidx);
            pTolerance = app.vrRewTol(envidx);
            nPosition = pPosition * app.envLength.Value / pLength;
            nTolerance = pTolerance * app.envLength.Value / pLength;
            app.vrRewPos(envidx) = nPosition;
            app.vrRewTol(envidx) = nTolerance;
            app.vrLength(envidx) = app.envLength.Value;
            updateSettingsMenus(app,envidx);
            envSettingsChanged(app,envidx) % propagate new setting throughout GUI
            presentMessage(app,sprintf('Length changed to %dcm for environment %d. Note that reward position & tolerance change relatively with length!',app.envLength.Value,envidx),1)
        end

        % Value changed function: dsFactor
        function dsFactorValueChanged(app, event)
            % first check if dsFactor leads to integer frames
            envidx = app.envSettings.Value;
            prevValue = event.PreviousValue;
            newValue = event.Value;
            frames = app.vrFrames(envidx);
            dsFrames = frames/newValue;
            if mod(dsFrames,1) ~= 0
                app.dsFactor.Value = prevValue;
                presentMessage(app,sprintf('dsFactor must divide %d frames for environment %d!',frames,envidx),2)
            else
                app.vrDSFactor(envidx) = newValue;
                app.vrFramesDS(envidx) = dsFrames;
                envSettingsChanged(app,envidx);
                presentMessage(app,sprintf('dsFactor changed to %d for environment %d, new frames=%d',newValue,envidx,dsFrames),1)
            end
        end

        % Value changed function: rewardPosition
        function rewardPositionValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrRewPos(envidx) = app.rewardPosition.Value;
            envSettingsChanged(app,envidx);
            rewUnits = 'cm';
            presentMessage(app,sprintf('Reward position changed to %d%s for environment %d',app.rewardPosition.Value,rewUnits,envidx),1)
        end

        % Value changed function: rewardTolerance
        function rewardToleranceValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrRewTol(envidx) = app.rewardTolerance.Value;
            envSettingsChanged(app,envidx);
            rewUnits = 'cm';
            presentMessage(app,sprintf('Reward tolerance changed to %d%s for environment %d',app.rewardTolerance.Value,rewUnits,envidx),1)
        end

        % Value changed function: envOrder
        function envOrderValueChanged(app, event)
            envidx = app.envSettings.Value; % current environment in settings 
            envorder = app.envOrder.Value; % new order for current environment

            idxActive = find(app.vrActive); % index of all active
            idxSelected = find(idxActive==envidx); % index of current environment among currently active
            prevOrder = app.vrOrder(idxActive); % previous order of all active
            
            newOrder = prevOrder; % setup new order array
            newOrder(idxSelected) = envorder; % update selected environment's order

            % Only one of these will happen...
            idxAboveOld = prevOrder>prevOrder(idxSelected) & prevOrder<=envorder;
            idxBelowOld = prevOrder<prevOrder(idxSelected) & prevOrder>=envorder;
            newOrder(idxAboveOld) = newOrder(idxAboveOld) - 1;
            newOrder(idxBelowOld) = newOrder(idxBelowOld) + 1;
            if any(idxAboveOld) && any(idxBelowOld)
                error('both these have trues!!');
            end

            % Update order!
            app.vrOrder(idxActive) = newOrder;
            if length(unique(app.vrOrder(idxActive))) < length(idxActive)
                error('order repeated somewhere');
            end
            
            % Update in use menu
            [~,newOrderIdx] = sort(newOrder);
            for env = 1:length(newOrderIdx)
                cenv = idxActive(newOrderIdx(env));
                app.vrenvInUse.Items{env} = app.envRichNames{cenv};
                app.vrenvInUse.ItemsData(env) = cenv;
            end

            % And update current environment's settings menu
            updateSettingsMenus(app,envidx) 
        end

        % Value changed function: presetBlocks
        function presetBlocksValueChanged(app, event)
            if app.presetBlocks.Value==0
                error('this should never be 0 after a user interaction');
            end
            changeBlockStructure(app, 'preset')
        end

        % Value changed function: randomBlocks
        function randomBlocksValueChanged(app, event)
            if app.randomBlocks.Value==0
                error('this should never be 0 after a user interaction');
            end
            changeBlockStructure(app, 'random')
        end

        % Button pushed function: printenvironmentinfoButton
        function printenvironmentinfoButtonPushed(app, event)
            filename = string(app.envBaseNames)';
            length = string(cellfun(@(length) sprintf('%dcm',length), num2cell(app.vrLength), 'uni', 0));
            rewPos = string(cellfun(@(rewpos) sprintf('%dcm',rewpos), num2cell(app.vrRewPos), 'uni', 0));
            rewTol = string(cellfun(@(rewtol) sprintf('%dcm',rewtol), num2cell(app.vrRewTol), 'uni', 0));
            
            frames = app.vrFrames;
            dsFrames = app.vrFramesDS;
            ds_factor = app.vrDSFactor;
            
            order = app.vrOrder;
            active = app.vrActive;
            tbl = table(filename,length,rewPos,rewTol,frames,dsFrames,ds_factor,order,active);
            disp(tbl);
        end

        % Value changed function: initActive
        function initActiveValueChanged(app, event)
           updateInitialBlockMenu(app)            
        end

        % Value changed function: initNumTrials
        function initNumTrialsValueChanged(app, event)
            updateInitialBlockMenu(app)
        end

        % Value changed function: sessionOffset
        function sessionOffsetValueChanged(app, event)
            if round(app.sessionOffset.Value / 100) * 100 ~= app.sessionOffset.Value
                presentMessage(app, sprintf('Session offset set to %i. It''s a ~very~ good idea to set this to a multiple of 100!',app.sessionOffset.Value), 2);
            end
        end

        % Value changed function: randomizeITI
        function randomizeITIValueChanged(app, event)
            if app.randomizeITI.Value, enableString = "on"; else, enableString = "off"; end
            app.distributionITI.Enable = enableString;
            app.randomMeanITI.Enable = enableString;
            app.randomMeanITILabel.Enable = enableString;
        end

        % Value changed function: randGain
        function randGainValueChanged(app, event)
            if app.randReward.Value || app.randGain.Value
                app.IndependentPRActiveLickingStoppingMovementGainTab.Title = '*Independent P(R), Active Licking & Stopping, Movement Gain*';
            else
                app.IndependentPRActiveLickingStoppingMovementGainTab.Title = 'Independent P(R), Active Licking & Stopping, Movement Gain';
            end
            % Update the value for the current environment if linked
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrRandomGain", app.randGain.Value)
            end
            if app.randGain.Value, enableString = "on"; else, enableString = "off"; end
            app.maxGain.Enable = enableString;
            app.maxGainLabel.Enable = enableString;
            app.minGain.Enable = enableString;
            app.minGainLabel.Enable = enableString;
        end

        % Value changed function: randReward
        function randRewardValueChanged(app, event)
            if app.randReward.Value || app.randGain.Value
                app.IndependentPRActiveLickingStoppingMovementGainTab.Title = '*Independent P(Reward) & Movement Gain*';
            else
                app.IndependentPRActiveLickingStoppingMovementGainTab.Title = 'Independent P(Reward) & Movement Gain';
            end
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrRandomReward", app.randReward.Value)
            end
            if app.randReward.Value
                enableString = "on"; 
            else 
                enableString = "off"; 
            end
            app.rewProbRemove.Enable = enableString;
            app.rewProbAddNew.Enable = enableString;
            app.rewProbList.Enable = enableString;
            app.rewProbNewFreq.Enable = enableString;
            app.rewProbNewFreqLabel.Enable = enableString;
            app.rewProbNewProb.Enable = enableString;
            app.rewProbNewProbLabel.Enable = enableString;
        end

        % Button pushed function: rewProbAddNew
        function rewProbAddNewButtonPushed(app, event)
            newProb = app.rewProbNewProb.Value; 
            newFreq = app.rewProbNewFreq.Value;
            if ismember(newProb,app.randRewardArray(:,1))
                % Then the requested reward probability is already in list
                % Default behavior is to add the frequency
                idxExisting = find(ismember(app.randRewardArray(:,1), newProb));
                app.randRewardArray(idxExisting,2) = app.randRewardArray(idxExisting,2) + newFreq;
                presentMessage(app, 'New reward probability already in list-- added to it''s frequency', 1);
            else
                app.randRewardArray(end+1,:) = [newProb newFreq];
                presentMessage(app, 'New reward probability option added to list', 1);
            end
            generateRewardProbList(app)
        end

        % Button pushed function: rewProbRemove
        function rewProbRemoveButtonPushed(app, event)
            cIndex = app.rewProbList.Value; 
            if isempty(cIndex)
                presentMessage(app, 'Can''t remove if none selected', 2);
                return
            end
            if size(app.randRewardArray,1) == 1
                presentMessage(app, 'Can''t remove last option', 2);
                return
            end
            app.randRewardArray(cIndex,:) = [];
            generateRewardProbList(app)
            presentMessage(app, 'Removed reward probability from list', 1);
        end

        % Button pushed function: saveSettings
        function saveSettingsPushed(app, event)
            if isempty(app.rigInfo.expSettingsDir)
                presentMessage(app, 'Can''t save settings without defined experiment settings path (stored in rigParameters())', 2);
                return
            end

            % Get new settings name from user
            [newSettingsName, newSettingsPath] = uiputfile(app.rigInfo.expSettingsDir + "/*.mat", 'Save as');
            if ~newSettingsName, return, end % If none selected, abort load
            if newSettingsPath(end) == "\", newSettingsPath = newSettingsPath(1:end-1); end
            if ~isequal(newSettingsPath, app.rigInfo.expSettingsDir)
                presentMessage(app, sprintf("Can only save settings in the designated settings folder set by rigParameters()! (%s)", app.rigInfo.expSettingsDir), 2);
                return
            end
            [~, app.settingsName.Value] = fileparts(newSettingsName);
            persistentVariables('settingsName',app.settingsName.Value);
            
            % Then actually do the saving
            settings = generateOutputStructure(app);
            savePath = fullfile(app.rigInfo.expSettingsDir,sprintf('%s.mat',app.settingsName.Value));
            save(savePath, 'settings');
            presentMessage(app, sprintf('Settings saved at: %s', savePath), 1);
        end

        % Button pushed function: loadSettings
        function loadSettingsButtonPushed(app, event)
            % Load new expSettings into the app, then load it as if...
            newSettingsName = uigetfile(app.rigInfo.expSettingsDir);
            if ~newSettingsName, return, end % If none selected, abort load
            [~, app.settingsName.Value] = fileparts(newSettingsName);
            persistentVariables('settingsName',app.settingsName.Value);
            figure(app.UIFigure);
            loadPath = fullfile(app.rigInfo.expSettingsDir,sprintf('%s.mat',app.settingsName.Value));
            if ~exist(loadPath,'file')
                error('This is incorrect below here...');
                presentMessage(app, 'Animal settings does not exist at: %s, check animal save path and animal name!', 2); %#ok need to fix the error
                highlightComponent(app, 'animalName');
                return
            end
            loadedSettings = load(loadPath,'settings');
            settings = loadedSettings.settings;
            
            % Load settings from current GUI window
            defaultSettings = generateOutputStructure(app);
            for field = fields(defaultSettings)
                % If the GUI has been updated to include new things, but
                % the settings file hasn't reflected that yet, then add the
                % default settings to the loaded settings struct and notify
                % the user. 
                if ~isfield(settings,field)
                    settings.(field) = defaultSettings.(field);
                    fprintf(2,'GUI contains parameter not found in loaded settings. ParameterName:{%s}\n',field);
                end
            end
            app.vrDirectory.Value = persistentVariables('vrDirectory',settings.vrDirectory);
            app.vrExtension.Value = persistentVariables('vrExtension',settings.vrExtension);
            % app.animalName.Value = persistentVariables('animalName',settings.animalName);
            app.sessionOffset.Value = persistentVariables('sessionOffset',settings.sessionOffset);
            app.maxTrialNumber.Value = persistentVariables('maxTrialNumber',settings.maxTrialNumber);
            app.maxTrialDuration.Value = persistentVariables('maxTrialDuration',settings.maxTrialDuration);
            app.useTrainingMode.Value = persistentVariables('useTrainingMode',settings.useTrainingMode);
            if isfield(settings, 'rewardSize')
                app.rewardSize.Value = settings.rewardSize;
            else
                app.rewardSize.Value = app.rigInfo.waterVolumeBASE;
            end

            % - below this are things that I don't really need persistent -
            app.envBaseNames = settings.vrOptions;
            app.vrLength = settings.vrLength;
            app.vrFrames = settings.vrFrames;
            app.vrFramesDS = settings.vrFramesDS;
            app.vrDSFactor = settings.vrDSFactor;
            app.vrRewPos = settings.vrRewPos; 
            app.vrRewTol = settings.vrRewTol;
            app.vrOrder = settings.vrOrder;
            app.vrActive = settings.vrActive;
            if isfield(settings,'preventBackwardMovement')
                app.backwardsMovementSwitch.Value = settings.preventBackwardMovement;
            else
                app.backwardsMovementSwitch.Value = 1;
            end
            app.minimumITI.Value = settings.minimumITI;
            app.randomizeITI.Value = settings.randomITI;
            if settings.randomITI
                app.randomMeanITI.Value = settings.randomMeanITI;
                app.distributionITI.Value = settings.distributionITI;
            end

            % Load environment options (needed before trial structure in
            % the case that env linked to trial types)
            loadEnvironmentOptions(app)
            
            % Trial Structure Loading
            app.trialTypesLinkedToEnvironment.Value = settings.trialTypesLinkedToEnvironment;
            if settings.trialTypesLinkedToEnvironment
                % Just set these vrEnv specific values, then use functional
                % callbacks to assign the actual current GUI values
                app.vrCondProbRew = settings.vrCondProbRew;
                app.vrCondActiveLick = settings.vrCondActiveLick;
                app.vrCondActiveStop = settings.vrCondActiveStop;
                app.vrCondMvmtGain = settings.vrCondMvmtGain;
                app.vrCondTrialReward = settings.vrCondTrialReward;
                app.vrCondTrialActiveLicking = settings.vrCondTrialActiveLicking;
                app.vrCondTrialActiveStopping = settings.vrCondTrialActiveStopping;
                app.vrCondTrialGain = settings.vrCondTrialGain;
                app.vrCondTrialFreq = settings.vrCondTrialFreq;
                app.vrRandomReward = settings.vrRandomReward;
                app.vrRandomRewardArray = settings.vrRandomRewardArray;
                app.vrActiveLicking = settings.vrActiveLicking;
                app.vrActiveStopping = settings.vrActiveStopping;
                app.vrActiveStopDuration = settings.vrActiveStopDuration;
                app.vrRandomGain = settings.vrRandomGain;
                app.vrMovementGain = settings.vrMovementGain;
                app.vrMinGain = settings.vrMinGain;
                app.vrMaxGain = settings.vrMaxGain;

                % To make sure the number of elements is constant in the
                % conditional trial arrays, we also have to set each
                % condTrial array directly, even if not used. (See below in
                % the else statement, app.condTrialGain won't be set unless
                % settings.condGain is true, for example, but that means
                % the current number of condTrialGain values might be
                % different than the current number of condTrialReward,
                % which will break some of the code's expectations.)
                app.condTrialReward = settings.vrCondTrialReward{1};
                app.condTrialActiveLicking= settings.vrCondTrialActiveLicking{1};
                app.condTrialActiveStopping = settings.vrCondTrialActiveStopping{1};
                app.condTrialGain = settings.vrCondTrialGain{1};

                % And finally update the settings menus to reflect the new
                % data with the selected environment
                updateSettingsMenus(app, app.envSettings.Value);
            else
                app.condProbRew.Value = settings.condReward;
                if settings.condReward
                    app.condTrialReward = settings.condTrialReward;
                else
                    app.randReward.Value = settings.randomReward;
                    if settings.randomReward
                        app.randRewardArray = settings.randomRewardArray;
                        generateRewardProbList(app)
                    end
                end
    
                app.condActiveLick.Value = settings.condActiveLicking;
                if settings.condActiveLicking
                    app.condTrialActiveLicking = settings.condTrialActiveLicking;
                end
                app.activeLicking.Value = settings.activeLicking;
                
                app.condActiveStop.Value = settings.condActiveStopping;
                if settings.condActiveStopping
                    app.condTrialActiveStopping = settings.condTrialActiveStopping;
                end
                app.activeStopping.Value = settings.activeStopping;
                app.stopDuration.Value = settings.stopDuration;
    
                app.condMvmtGain.Value = settings.condGain;
                if settings.condGain
                    app.condTrialGain = settings.condTrialGain;
                else
                    app.movementGain.Value = settings.movementGain;
                    app.randGain.Value = settings.randomGain;
                    if settings.randomGain
                        app.minGain.Value = settings.minGain;
                        app.maxGain.Value = settings.maxGain;
                    end
                end
    
                % And get the frequencies if there are conditional trials
                if settings.condReward || settings.condActiveLicking || settings.condActiveStopping || settings.condGain
                    app.condTrialFreq = settings.condTrialFreq;
                    needLength = true;
                    if needLength && settings.condReward
                        numCondTrial = length(settings.condTrialReward);
                        needLength = false;
                    end
                    if needLength && settings.condActiveLicking
                        numCondTrial = length(settings.condTrialActiveLicking);
                        needLength = false;
                    end
                    if needLength && settings.condActiveStopping
                        numCondTrial = length(settings.condTrialActiveStopping);
                        needLength = false;
                    end
                    if needLength && settings.condGain
                        numCondTrial = length(settings.condTrialGain);
                    end
                    if ~settings.condReward, app.condTrialReward = ones(1,numCondTrial); end
                    if ~settings.condActiveLicking, app.condTrialActiveLicking = app.activeLicking.Value*ones(1,numCondTrial); end
                    if ~settings.condActiveStopping, app.condTrialActiveStopping = app.activeStopping.Value*app.stopDuration.Value*ones(1,numCondTrial); end
                    if ~settings.condGain, app.condTrialGain = ones(1,numCondTrial); end
                    if length(app.condTrialFreq) ~= numCondTrial
                        error('Conditional trial frequency doesn''t have same length as other conditional trial vectors...'); 
                    end
                end
                % Finally, update conditional structure (which takes care of
                % independent settings too)
                updateConditionalStructure(app)
            end

            updateInitialBlockMenu(app)
            app.initActive.Value = settings.initBlock;
            if settings.initBlock
                app.initEnvironment.Value = settings.initEnvIdx;
                app.initNumTrials.Value = settings.initTrials;
            end
            updateInitialBlockMenu(app)
            
            blockType = settings.blockType;
            blockNames = {'Preset','Random'};
            app.presetBlocks.Value = blockType(1);
            app.randomBlocks.Value = blockType(2);
            app.vrNumPresetTrials = app.envTrials.Value*ones(length(app.vrLength),1);
            app.vrNumRandTrials = app.randomLengthMean.Value*ones(length(app.vrLength),1);
            if blockType(1)
                app.vrNumPresetTrials = settings.blockTrialsPer;
                app.evenTrialsPerEnv.Value = settings.evenTrialsPerEnv;
            end
            if blockType(2)
                app.vrNumRandTrials = settings.blockRandomMean;
                app.evenRandMeanPerEnv.Value = settings.evenRandMeanPerEnv;
                app.randomDistribution.Value = settings.blockRandomDistribution;
            end
            updatePresetTrialsList(app);
            updateRandomTrialsList(app);
            
            % Now that everything is loaded, resume GUI operation
            changeBlockStructure(app, blockNames{blockType});
            % loadEnvironmentOptions(app)
            randomizeITIValueChanged(app)
            presentMessage(app, 'Previous settings loaded!', 1);
        end

        % Value changed function: animalName
        function animalNameValueChanged(app, event)
            persistentVariables('animalName', app.animalName.Value);
        end

        % Button pushed function: printrigparametersButton
        function printrigparametersButtonPushed(app, event)
            disp(app.rigInfo)
        end

        % Button pushed function: previewExperiment
        function previewExperimentButtonPushed(app, event)
            settings = generateOutputStructure(app);
            trialStructure = createTrialStructure(settings);
            
            % Generate trial output structure
            trialStructure.envName = string(cellfun(@(idx) ...
                trialStructure.getEnvName(idx), num2cell(trialStructure.envIndex), 'uni', 0));

            tbl = table(...
                (1:trialStructure.maxTrials)', ...
                trialStructure.envIndex, ...
                trialStructure.envName, ...
                trialStructure.roomLength, ...
                trialStructure.rewardPosition, ...
                trialStructure.rewardTolerance, ...
                trialStructure.probReward, ...
                trialStructure.activeLick, ...
                trialStructure.activeStop, ...
                trialStructure.mvmtGain, ...
                trialStructure.intertrialInterval, ...
                'variableNames',{'Trial #','EnvIdx','EnvName','Length','RewPos','RewTol','P(R)','Act.Lick','StopDuration','MvmtGain','ITI'});
            disp(tbl)

            fprintf('Note: any randomness will be resampled when the actual experiment is run!! \n\n'); 

            % then print to screen here...
            
            % Immediate To-Do!!!
            % trials.vrIndex
            % trials.rewardLocation
            % trials.rewardAvailable
            % trials.activeLicking...
            % so on...
        end

        % Value changed function: condTrialList
        function condTrialListValueChanged(app, event)
            cCondIdx = app.condTrialList.Value;
            app.condProbRewSetting.Value = app.condTrialReward(cCondIdx);
            app.condActiveLickSetting.Value = app.condTrialActiveLicking(cCondIdx);
            app.condActiveStopDuration.Value = app.condTrialActiveStopping(cCondIdx);
            app.condMvmtGainSetting.Value = app.condTrialGain(cCondIdx);
            app.condFreqSetting.Value = app.condTrialFreq(cCondIdx);
        end

        % Value changed function: condProbRew
        function condProbRew_Pushed(app, event)
            updateConditionalStructure(app);
        end

        % Value changed function: condMvmtGain
        function condMvmtGainValueChanged(app, event)
            updateConditionalStructure(app);
        end

        % Value changed function: condActiveLick
        function condActiveLickValueChanged(app, event)
            if app.condActiveLick.Value
                if ~app.lickEncoderAvailable.Value
                    presentMessage(app, 'Note: according to hardware info (in rigInfo), lick encoder not available, so this setting is permitted but ignored!!', 2);
                    highlightComponent(app, 'lickEncoderAvailable', 'FontColor');
                end
            end           
            updateConditionalStructure(app);
        end

        % Value changed function: condActiveStop
        function condActiveStopValueChanged(app, event)
            updateConditionalStructure(app);
        end

        % Button pushed function: addnewButton
        function addnewButtonPushed(app, event)
            newProb = app.condProbRewSetting.Value; 
            newActLick = app.condActiveLickSetting.Value; 
            newActStop = app.condActiveStopDuration.Value; 
            newGain = app.condMvmtGainSetting.Value; 
            newFreq = app.condFreqSetting.Value;
            compareReward = newProb==app.condTrialReward; 
            compareActLick = newActLick==app.condTrialActiveLicking;
            compareActStop = newActStop==app.condTrialActiveStopping;
            compareGain = newGain==app.condTrialGain;
            if ~app.condProbRew.Value, compareReward(:)=true; end
            if ~app.condActiveLick.Value, compareActLick(:)=true; end
            if ~app.condActiveStop.Value, compareActStop(:)=true; end
            if ~app.condMvmtGain.Value, compareGain(:)=true; end
            idxAlready = find(compareReward & compareActLick & compareActStop & compareGain); 
            if ~isempty(idxAlready)
                % Then the requested conditional trial type is already in list
                % Default behavior is to add the frequency
                updatedFreq = app.condTrialFreq(idxAlready) + newFreq;
                if updatedFreq<=0
                    presentMessage(app, 'Trial type frequencies must be positive...', 2);
                    return
                end
                app.condTrialFreq(idxAlready) = updatedFreq;
                presentMessage(app, 'New conditional trial type already in list-- added to it''s frequency', 1);
            elseif length(idxAlready) > 1
                error('Found multiple identical conditional trial types...');
            else
                if newFreq<=0
                    presentMessage(app, 'Trial type frequencies must be positive...', 2);
                    return
                end
                app.condTrialReward(end+1) = newProb;
                app.condTrialActiveLicking(end+1) = newActLick;
                app.condTrialActiveStopping(end+1) = newActStop;
                app.condTrialGain(end+1) = newGain;
                app.condTrialFreq(end+1) = newFreq;
                presentMessage(app, 'New conditional trial type added to list', 1);
            end
            handleChangeToConditionalTrials(app)
            
            % Redo this to make sure we've actually selected the new one
            app.condTrialList.Value = length(app.condTrialReward);
            condTrialListValueChanged(app)
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            cIndex = app.condTrialList.Value; 
            if isempty(cIndex)
                presentMessage(app, 'Can''t remove if none selected', 2);
                return
            end
            if length(app.condTrialReward) == 1
                presentMessage(app, 'Can''t remove last option', 2);
                return
            end
            app.condTrialReward(cIndex)=[];
            app.condTrialActiveLicking(cIndex)=[];
            app.condTrialGain(cIndex)=[];
            app.condTrialFreq(cIndex)=[];
            handleChangeToConditionalTrials(app)
            presentMessage(app, 'Removed conditional trial type from list', 1);
        end

        % Button pushed function: updatetrialtypeButton
        function updatetrialtypeButtonPushed(app, event)
            cIndex = app.condTrialList.Value;
            if isempty(cIndex)
                presentMessage(app, 'Can''t update if none selected', 2);
                return
            end
            app.condTrialReward(cIndex) = app.condProbRewSetting.Value;
            app.condTrialActiveLicking(cIndex) = app.condActiveLickSetting.Value;
            app.condTrialActiveStopping(cIndex) = app.condActiveStopDuration.Value;
            app.condTrialGain(cIndex) = app.condMvmtGainSetting.Value;
            app.condTrialFreq(cIndex) = app.condFreqSetting.Value;
            handleChangeToConditionalTrials(app)

            % Do some reporting if necessary
            msg = 'Updated conditional trial type.'; 
            msgType = 1; 
            if app.condActiveLickSetting.Value && ~app.lickEncoderAvailable.Value
                msg = ['Updated conditional trial type. ',...
                    'Note: according to hardware info (in rigInfo), lick encoder not available, so this setting is permitted but ignored!!'];
                msgType = 2; 
            end
            presentMessage(app, msg, msgType);
            if msgType==2, highlightComponent(app,'lickEncoderAvailable','FontColor'); end
        end

        % Button pushed function: runExperiment
        function runExperimentButtonPushed(app, event)
            settings = generateOutputStructure(app);
            if isempty(settings.animalName)
                msg = 'Cannot start an experiment without defining animal name!';
                presentMessage(app, msg, 2)
                highlightComponent(app, 'animalName', 'BackgroundColor')
                return 
            end
            delete(app)
            runExperiment(settings); %#ok this is a function with the same name as the button
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            doThisNonsense = false;
            if doThisNonsense
                answer = questdlg('Choose an option:', ...
                    'Closing vrControl GUI', 'Run Experiment', 'Save settings & close', 'Close without saving', 'Close without saving'); %#ok
                switch answer
                    case 'Run Experiment'
                        runExperimentButtonPushed(app);
                    case 'Save settings & close'
                        saveSettingsPushed(app);
                    case 'Close without saving'
                end
            end
            delete(app)
        end

        % Value changed function: stopDuration
        function stopDurationValueChanged(app, event)
            if app.stopDuration.Value==0
                app.activeStopping.Value = false;
                app.stopDuration.Enable = "off";
                app.stopDurationLabel.Enable = "off";
            end
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrActiveStopping", app.activeStopping.Value)
                updateVrenvTrialType(app, "vrActiveStopDuration", app.stopDuration.Value)
            end
        end

        % Value changed function: activeStopping
        function activeStoppingValueChanged(app, event)
            if app.activeStopping.Value
                app.stopDuration.Enable = "on";
                app.stopDurationLabel.Enable = "on";
                if app.stopDuration.Value == 0
                    % Make sure it's a valid value if stopping is on
                    app.stopDuration.Value = 1;
                end
            else
                app.stopDuration.Enable = "off";
                app.stopDurationLabel.Enable = "off";
            end
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrActiveStopping", app.activeStopping.Value)
            end
        end

        % Value changed function: envTrials
        function envTrialsValueChanged(app, event)
            if app.evenTrialsPerEnv.Value
                app.vrNumPresetTrials(:) = app.envTrials.Value;
            else
                cEnvIdx = app.presetTrialsList.Value;
                app.vrNumPresetTrials(cEnvIdx) = app.envTrials.Value;
            end
            updatePresetTrialsList(app);
        end

        % Value changed function: presetTrialsList
        function presetTrialsListValueChanged(app, event)
            cEnvIdx = app.presetTrialsList.Value;
            app.envTrials.Value = app.vrNumPresetTrials(cEnvIdx);
            updatePresetTrialsList(app);
        end

        % Value changed function: evenTrialsPerEnv
        function evenTrialsPerEnvValueChanged(app, event)
            if app.evenTrialsPerEnv.Value % If even for all -- set it to the currently displayed value
                app.vrNumPresetTrials(:) = app.envTrials.Value;
            end
            updatePresetTrialsList(app);
        end

        % Value changed function: randomLengthMean
        function randomLengthMeanValueChanged(app, event)
            if app.evenRandMeanPerEnv.Value
                app.vrNumRandTrials(:) = app.randomLengthMean.Value;
            else
                cEnvIdx = app.randomMeanList.Value;
                app.vrNumRandTrials(cEnvIdx) = app.randomLengthMean.Value;
            end
            updateRandomTrialsList(app);
        end

        % Value changed function: randomMeanList
        function randomMeanListValueChanged(app, event)
            cEnvIdx = app.randomMeanList.Value;
            app.randomLengthMean.Value = app.vrNumRandTrials(cEnvIdx);
            updateRandomTrialsList(app);
        end

        % Value changed function: evenRandMeanPerEnv
        function evenRandMeanPerEnvValueChanged(app, event)
            if app.evenRandMeanPerEnv.Value
                app.vrNumRandTrials(:) = app.randomLengthMean.Value;
            end
            updateRandomTrialsList(app);
        end

        % Value changed function: activeLicking
        function activeLickingValueChanged(app, event)
            if app.activeLicking.Value && ~app.lickEncoderAvailable.Value
                presentMessage(app, 'Note: according to hardware info (in rigInfo), lick encoder not available, so this setting is permitted but ignored!!', 2);
                highlightComponent(app, 'lickEncoderAvailable', 'FontColor');
            end
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrActiveLicking", app.activeLicking.Value)
            end
        end

        % Value changed function: trialTypesLinkedToEnvironment
        function trialTypesLinkedToEnvironmentValueChanged(app, event)
            value = app.trialTypesLinkedToEnvironment.Value;
            numEnvOptions = length(app.vrenvOptions.Items);
            if value && (numEnvOptions == 0)
                presentMessage(app, "Can only link trial types to environment if there are vrEnv options available!", 2)
                highlightComponent(app, "updateDirectory", "BackgroundColor")
                app.trialTypesLinkedToEnvironment.Value = false;
            end
            switchToLinkingTrialsToEnvs(app);
        end

        % Value changed function: movementGain
        function movementGainValueChanged(app, event)
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrMovementGain", app.movementGain.Value)
            end
        end

        % Value changed function: minGain
        function minGainValueChanged(app, event)
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrMinGain", app.minGain.Value)
            end
        end

        % Value changed function: maxGain
        function maxGainValueChanged(app, event)
            if app.trialTypesLinkedToEnvironment.Value
                updateVrenvTrialType(app, "vrMaxGain", app.maxGain.Value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.topBox = uitextarea(app.UIFigure);
            app.topBox.Tooltip = {'Control a linear track experiment using pre-rendered movies.'};
            app.topBox.Value = {'vrControl'};
            app.vrenvInUseLabel = uilabel(app.UIFigure);
            app.vrenvInUseLabel.Text = 'Virtual Environments In Use - Double Click to Deactivate';
            app.vrenvOptionsLabel = uilabel(app.UIFigure);
            app.vrenvOptionsLabel.Text = 'VR Options - Double Click to Activate';
            app.printenvironmentinfoButton = uibutton(app.UIFigure, 'push');
            app.printenvironmentinfoButton.ButtonPushedFcn = createCallbackFcn(app, @printenvironmentinfoButtonPushed, true);
            app.printenvironmentinfoButton.Text = 'print environment info';
            app.envActive = uibutton(app.UIFigure, 'state');
            app.envActive.ValueChangedFcn = createCallbackFcn(app, @envActiveValueChanged, true);
            app.envActive.Enable = 'off';
            app.envActive.Tooltip = {'toggle to activate environment (redundant with item selection in listbox)'};
            app.envActive.Text = 'env active';
            app.envOrderLabel = uilabel(app.UIFigure);
            app.envOrderLabel.Enable = 'off';
            app.envOrderLabel.Text = 'Order in block structure';
            app.envOrder = uidropdown(app.UIFigure);
            app.envOrder.Items = {'n/a', '1', '2'};
            app.envOrder.ItemsData = [-1 1 2];
            app.envOrder.ValueChangedFcn = createCallbackFcn(app, @envOrderValueChanged, true);
            app.envOrder.Enable = 'off';
            app.envOrder.Tooltip = {'determines order of environment in block structure (if random env selection is on, then this is irrelevant)'};
            app.envOrder.Value = -1;
            app.rewardToleranceLabel = uilabel(app.UIFigure);
            app.rewardToleranceLabel.Enable = 'off';
            app.rewardToleranceLabel.Text = 'Reward Tolerance';
            app.rewardTolerance = uieditfield(app.UIFigure, 'numeric');
            app.rewardTolerance.Limits = [0 2000];
            app.rewardTolerance.ValueChangedFcn = createCallbackFcn(app, @rewardToleranceValueChanged, true);
            app.rewardTolerance.Enable = 'off';
            app.rewardTolerance.Tooltip = {'length of reward zone in units of percentage or cm'};
            app.rewardTolerance.Value = 10;
            app.rewardPositionLabel = uilabel(app.UIFigure);
            app.rewardPositionLabel.Enable = 'off';
            app.rewardPositionLabel.Text = 'Reward Position';
            app.rewardPosition = uieditfield(app.UIFigure, 'numeric');
            app.rewardPosition.Limits = [0 2000];
            app.rewardPosition.ValueChangedFcn = createCallbackFcn(app, @rewardPositionValueChanged, true);
            app.rewardPosition.Enable = 'off';
            app.rewardPosition.Tooltip = {'length of reward zone in units of percentage or cm'};
            app.rewardPosition.Value = 200;
            app.dsFactorLabel = uilabel(app.UIFigure);
            app.dsFactorLabel.Enable = 'off';
            app.dsFactorLabel.Text = 'Downsample Factor';
            app.dsFactor = uieditfield(app.UIFigure, 'numeric');
            app.dsFactor.Limits = [1 1000];
            app.dsFactor.RoundFractionalValues = 'on';
            app.dsFactor.ValueChangedFcn = createCallbackFcn(app, @dsFactorValueChanged, true);
            app.dsFactor.Enable = 'off';
            app.dsFactor.Tooltip = {'factor to downsample environment arrays (for quick loading and testing)'};
            app.dsFactor.Value = 120;
            app.envLengthLabel = uilabel(app.UIFigure);
            app.envLengthLabel.Enable = 'off';
            app.envLengthLabel.Text = 'Env Length (cm)';
            app.envLength = uieditfield(app.UIFigure, 'numeric');
            app.envLength.Limits = [1 2000];
            app.envLength.RoundFractionalValues = 'on';
            app.envLength.ValueChangedFcn = createCallbackFcn(app, @envLengthValueChanged, true);
            app.envLength.Enable = 'off';
            app.envLength.Tooltip = {'length of corridor (in centimeters)'};
            app.envLength.Value = 250;
            app.envSettingsLabel = uilabel(app.UIFigure);
            app.envSettingsLabel.Enable = 'off';
            app.envSettingsLabel.Text = 'Update Environnment Settings';
            app.envSettings = uidropdown(app.UIFigure);
            app.envSettings.Items = {'n/a'};
            app.envSettings.ValueChangedFcn = createCallbackFcn(app, @envSettingsValueChanged, true);
            app.envSettings.Enable = 'off';
            app.envSettings.Tooltip = {'select environment to update its settings'};
            app.envSettings.Value = 'n/a';
            app.environmentBlockTab = uitabgroup(app.UIFigure);
            app.environmentBlockTab.Tooltip = {'design block structure for virtual environments'};
            app.InitialTab = uitab(app.environmentBlockTab);
            app.InitialTab.Title = 'Initial';
            app.initEnvironmentLabel = uilabel(app.InitialTab);
            app.initEnvironmentLabel.Text = 'Environment';
            app.initEnvironment = uidropdown(app.InitialTab);
            app.initEnvironment.Items = {'n/a'};
            app.initEnvironment.ItemsData = -1;
            app.initEnvironment.Tooltip = {'set environment to be used for initial block'};
            app.initEnvironment.Value = -1;
            app.initNumTrialsLabel = uilabel(app.InitialTab);
            app.initNumTrialsLabel.Text = 'Number of Trials';
            app.initNumTrials = uieditfield(app.InitialTab, 'numeric');
            app.initNumTrials.Limits = [0 500];
            app.initNumTrials.RoundFractionalValues = 'on';
            app.initNumTrials.ValueChangedFcn = createCallbackFcn(app, @initNumTrialsValueChanged, true);
            app.initNumTrials.Tooltip = {'set number of trials in initial block'};
            app.initNumTrials.Value = 10;
            app.initActive = uicheckbox(app.InitialTab);
            app.initActive.ValueChangedFcn = createCallbackFcn(app, @initActiveValueChanged, true);
            app.initActive.Tooltip = {'use an initial block of one environment, then switch to using all environments'};
            app.initActive.Text = 'Use Initial Block';
            app.PresetTab = uitab(app.environmentBlockTab);
            app.PresetTab.Tooltip = {'one environment per block, same number of trials for all blocks'};
            app.PresetTab.Title = '*Preset*';
            app.presetTrialsList = uilistbox(app.PresetTab);
            app.presetTrialsList.Items = {};
            app.presetTrialsList.ValueChangedFcn = createCallbackFcn(app, @presetTrialsListValueChanged, true);
            app.presetTrialsList.Value = {};
            app.envTrialsLabel = uilabel(app.PresetTab);
            app.envTrialsLabel.Text = '# Trials';
            app.envTrials = uieditfield(app.PresetTab, 'numeric');
            app.envTrials.Limits = [1 Inf];
            app.envTrials.ValueChangedFcn = createCallbackFcn(app, @envTrialsValueChanged, true);
            app.envTrials.Tooltip = {'set # of trials per environment for each block of that environment'};
            app.envTrials.Value = 5;
            app.evenTrialsPerEnv = uibutton(app.PresetTab, 'state');
            app.evenTrialsPerEnv.ValueChangedFcn = createCallbackFcn(app, @evenTrialsPerEnvValueChanged, true);
            app.evenTrialsPerEnv.Tooltip = {'if toggled on, then all environments have same block length'};
            app.evenTrialsPerEnv.Text = 'equal blocks';
            app.evenTrialsPerEnv.Value = true;
            app.presetBlocks = uicheckbox(app.PresetTab);
            app.presetBlocks.ValueChangedFcn = createCallbackFcn(app, @presetBlocksValueChanged, true);
            app.presetBlocks.Enable = 'off';
            app.presetBlocks.Tooltip = {'use preset block structure'};
            app.presetBlocks.Text = 'Preset Blocks';
            app.presetBlocks.Value = true;
            app.RandomTab = uitab(app.environmentBlockTab);
            app.RandomTab.Tooltip = {'one environment per block, randomly selected number of trials per block'};
            app.RandomTab.Title = 'Random';
            app.randomMeanList = uilistbox(app.RandomTab);
            app.randomMeanList.Items = {};
            app.randomMeanList.ValueChangedFcn = createCallbackFcn(app, @randomMeanListValueChanged, true);
            app.randomMeanList.Enable = 'off';
            app.randomMeanList.Value = {};
            app.randomLengthMeanLabel = uilabel(app.RandomTab);
            app.randomLengthMeanLabel.Enable = 'off';
            app.randomLengthMeanLabel.Text = 'Mean';
            app.randomLengthMean = uieditfield(app.RandomTab, 'numeric');
            app.randomLengthMean.Limits = [1 Inf];
            app.randomLengthMean.RoundFractionalValues = 'on';
            app.randomLengthMean.ValueChangedFcn = createCallbackFcn(app, @randomLengthMeanValueChanged, true);
            app.randomLengthMean.Enable = 'off';
            app.randomLengthMean.Tooltip = {'mean for distribution selected above'};
            app.randomLengthMean.Value = 5;
            app.randomDistribution = uidropdown(app.RandomTab);
            app.randomDistribution.Items = {'Poisson'};
            app.randomDistribution.ItemsData = 1;
            app.randomDistribution.Enable = 'off';
            app.randomDistribution.Tooltip = {'distribution used for setting each block length'};
            app.randomDistribution.Value = 1;
            app.evenRandMeanPerEnv = uibutton(app.RandomTab, 'state');
            app.evenRandMeanPerEnv.ValueChangedFcn = createCallbackFcn(app, @evenRandMeanPerEnvValueChanged, true);
            app.evenRandMeanPerEnv.Enable = 'off';
            app.evenRandMeanPerEnv.Tooltip = {'if toggled on, then all environments have same block length'};
            app.evenRandMeanPerEnv.Text = 'allsame';
            app.evenRandMeanPerEnv.Value = true;
            app.randomBlocks = uicheckbox(app.RandomTab);
            app.randomBlocks.ValueChangedFcn = createCallbackFcn(app, @randomBlocksValueChanged, true);
            app.randomBlocks.Tooltip = {'use random block structure'};
            app.randomBlocks.Text = 'Random';
            app.EnvironmentBlockStructureLabel = uilabel(app.UIFigure);
            app.EnvironmentBlockStructureLabel.Text = 'Environment Block Structure';
            app.messagestouserTextAreaLabel = uilabel(app.UIFigure);
            app.messagestouserTextAreaLabel.Text = 'messages to user';
            app.messagestouserTextArea = uitextarea(app.UIFigure);
            app.messagestouserTextArea.Editable = 'off';
            app.messagestouserTextArea.Tooltip = {'This window displays messages to user. It updates to red if there is a passable error. All updates are stored and available upon output of vrControl GUI.  '};
            app.trialSettingsTabs = uitabgroup(app.UIFigure);
            app.ConditionalTrialTypesTab = uitab(app.trialSettingsTabs);
            app.ConditionalTrialTypesTab.Title = '*Conditional Trial Types*';
            app.removeButton = uibutton(app.ConditionalTrialTypesTab, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.addnewButton = uibutton(app.ConditionalTrialTypesTab, 'push');
            app.addnewButton.ButtonPushedFcn = createCallbackFcn(app, @addnewButtonPushed, true);
            app.addnewButton.Text = 'add new';
            app.condMvmtGain = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condMvmtGain.ValueChangedFcn = createCallbackFcn(app, @condMvmtGainValueChanged, true);
            app.condMvmtGain.Text = 'mvmt gain cond.';
            app.condActiveStop = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condActiveStop.ValueChangedFcn = createCallbackFcn(app, @condActiveStopValueChanged, true);
            app.condActiveStop.Text = 'active stop cond.';
            app.condActiveLick = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condActiveLick.ValueChangedFcn = createCallbackFcn(app, @condActiveLickValueChanged, true);
            app.condActiveLick.Text = 'active lick cond.';
            app.condActiveLick.Value = true;
            app.condProbRew = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condProbRew.ValueChangedFcn = createCallbackFcn(app, @condProbRew_Pushed, true);
            app.condProbRew.Text = 'p(reward) cond.';
            app.condProbRew.Value = true;
            app.condFreqSettingLabel = uilabel(app.ConditionalTrialTypesTab);
            app.condFreqSettingLabel.Text = 'Frequency';
            app.condFreqSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condFreqSetting.RoundFractionalValues = 'on';
            app.condFreqSetting.Value = 1;
            app.condMvmtGainSettingLabel = uilabel(app.ConditionalTrialTypesTab);
            app.condMvmtGainSettingLabel.Text = 'Mvmt Gain';
            app.condMvmtGainSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condMvmtGainSetting.Limits = [0 Inf];
            app.condMvmtGainSetting.Tooltip = {'duration of active stop!'};
            app.condMvmtGainSetting.Value = 1;
            app.condActiveStopDurationLabel = uilabel(app.ConditionalTrialTypesTab);
            app.condActiveStopDurationLabel.Text = 'Active Stop';
            app.condActiveStopDuration = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condActiveStopDuration.Limits = [0 Inf];
            app.condActiveLickSettingLabel = uilabel(app.ConditionalTrialTypesTab);
            app.condActiveLickSettingLabel.Text = 'Active Lick';
            app.condActiveLickSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condActiveLickSetting.Limits = [0 1];
            app.condActiveLickSetting.RoundFractionalValues = 'on';
            app.condActiveLickSetting.Tooltip = {'whether active licks are required for this trial type'};
            app.condActiveLickSetting.Value = 1;
            app.condProbRewSettingLabel = uilabel(app.ConditionalTrialTypesTab);
            app.condProbRewSettingLabel.Text = 'P(reward)';
            app.condProbRewSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condProbRewSetting.Limits = [0 1];
            app.condProbRewSetting.Value = 1;
            app.condTrialList = uilistbox(app.ConditionalTrialTypesTab);
            app.condTrialList.Items = {'P(R)=1, AL=1, AS=1, MG=1, 100%, Freq=1'};
            app.condTrialList.ValueChangedFcn = createCallbackFcn(app, @condTrialListValueChanged, true);
            app.condTrialList.Value = 'P(R)=1, AL=1, AS=1, MG=1, 100%, Freq=1';
            app.updatetrialtypeButton = uibutton(app.ConditionalTrialTypesTab, 'push');
            app.updatetrialtypeButton.ButtonPushedFcn = createCallbackFcn(app, @updatetrialtypeButtonPushed, true);
            app.updatetrialtypeButton.Text = 'update trial type';
            app.vrenvSelectionIndicatorConditional = uilabel(app.ConditionalTrialTypesTab);
            app.vrenvSelectionIndicatorConditional.Tooltip = {'use the button below this "link trial types" to make trial types conditional on the vrEnv'};
            app.vrenvSelectionIndicatorConditional.Text = 'trial types are currently independent of VR environment';
            app.IndependentPRActiveLickingStoppingMovementGainTab = uitab(app.trialSettingsTabs);
            app.IndependentPRActiveLickingStoppingMovementGainTab.Title = 'Independent P(R), Active Licking & Stopping, Movement Gain';
            app.stopDurationLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.stopDurationLabel.Enable = 'off';
            app.stopDurationLabel.Text = 'Stop Duration';
            app.stopDuration = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.stopDuration.Limits = [0 Inf];
            app.stopDuration.ValueChangedFcn = createCallbackFcn(app, @stopDurationValueChanged, true);
            app.stopDuration.Enable = 'off';
            app.activeStopping = uicheckbox(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.activeStopping.ValueChangedFcn = createCallbackFcn(app, @activeStoppingValueChanged, true);
            app.activeStopping.Tooltip = {'If checked, will use lick encoder'};
            app.activeStopping.Text = 'Active Stopping';
            app.activeLicking = uicheckbox(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.activeLicking.ValueChangedFcn = createCallbackFcn(app, @activeLickingValueChanged, true);
            app.activeLicking.Enable = 'off';
            app.activeLicking.Tooltip = {'If checked, will use lick encoder'};
            app.activeLicking.Text = 'Active Licking';
            app.activeLicking.Value = true;
            app.rewProbRemove = uibutton(app.IndependentPRActiveLickingStoppingMovementGainTab, 'push');
            app.rewProbRemove.ButtonPushedFcn = createCallbackFcn(app, @rewProbRemoveButtonPushed, true);
            app.rewProbRemove.Enable = 'off';
            app.rewProbRemove.Text = 'rmv';
            app.rewProbAddNew = uibutton(app.IndependentPRActiveLickingStoppingMovementGainTab, 'push');
            app.rewProbAddNew.ButtonPushedFcn = createCallbackFcn(app, @rewProbAddNewButtonPushed, true);
            app.rewProbAddNew.Enable = 'off';
            app.rewProbAddNew.Text = 'add';
            app.rewProbNewFreqLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.rewProbNewFreqLabel.Enable = 'off';
            app.rewProbNewFreqLabel.Text = 'Frequency';
            app.rewProbNewFreq = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.rewProbNewFreq.Limits = [0 Inf];
            app.rewProbNewFreq.Enable = 'off';
            app.rewProbNewFreq.Value = 1;
            app.rewProbNewProbLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.rewProbNewProbLabel.Enable = 'off';
            app.rewProbNewProbLabel.Text = 'P(reward)';
            app.rewProbNewProb = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.rewProbNewProb.Limits = [0 1];
            app.rewProbNewProb.Enable = 'off';
            app.rewProbNewProb.Value = 0.5;
            app.rewProbList = uilistbox(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.rewProbList.Items = {};
            app.rewProbList.Enable = 'off';
            app.rewProbList.Value = {};
            app.randReward = uicheckbox(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.randReward.ValueChangedFcn = createCallbackFcn(app, @randRewardValueChanged, true);
            app.randReward.Enable = 'off';
            app.randReward.Tooltip = {'randomize reward probability on each trial'};
            app.randReward.Text = 'Random reward probability';
            app.maxGainLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.maxGainLabel.Enable = 'off';
            app.maxGainLabel.Text = 'max';
            app.maxGain = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.maxGain.Limits = [1 10];
            app.maxGain.ValueChangedFcn = createCallbackFcn(app, @maxGainValueChanged, true);
            app.maxGain.Enable = 'off';
            app.maxGain.Tooltip = {'maximum possible gain'};
            app.maxGain.Value = 2;
            app.minGainLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.minGainLabel.Enable = 'off';
            app.minGainLabel.Text = 'min';
            app.minGain = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.minGain.Limits = [0 1];
            app.minGain.ValueChangedFcn = createCallbackFcn(app, @minGainValueChanged, true);
            app.minGain.Enable = 'off';
            app.minGain.Tooltip = {'minimum possible gain'};
            app.minGain.Value = 0.5;
            app.randGain = uicheckbox(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.randGain.ValueChangedFcn = createCallbackFcn(app, @randGainValueChanged, true);
            app.randGain.Tooltip = {'randomize gain between movement and VR corridor'};
            app.randGain.Text = 'Randomize';
            app.movementGainLabel = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.movementGainLabel.Tooltip = {'sets the baseline gain -- advisable to set this =1 because it''s redundant with "env length"'};
            app.movementGainLabel.Text = 'Baseline Gain Value';
            app.movementGain = uieditfield(app.IndependentPRActiveLickingStoppingMovementGainTab, 'numeric');
            app.movementGain.Limits = [0.01 10];
            app.movementGain.ValueChangedFcn = createCallbackFcn(app, @movementGainValueChanged, true);
            app.movementGain.Tooltip = {'sets the baseline gain -- advisable to set this =1 because it''s redundant with "env length"'};
            app.movementGain.Value = 1;
            app.vrenvSelectionIndicatorIndependent = uilabel(app.IndependentPRActiveLickingStoppingMovementGainTab);
            app.vrenvSelectionIndicatorIndependent.Tooltip = {'use the button below this "link trial types" to make trial types conditional on the vrEnv'};
            app.vrenvSelectionIndicatorIndependent.Text = 'trial types are currently independent of VR environment';
            app.IntertrialTimingsPanel = uipanel(app.UIFigure);
            app.IntertrialTimingsPanel.Title = 'Intertrial Timings';
            app.distributionITI = uidropdown(app.IntertrialTimingsPanel);
            app.distributionITI.Items = {'Uniform', 'Exponential'};
            app.distributionITI.ItemsData = [1 2];
            app.distributionITI.Tooltip = {'distribution used for generating ITIs'};
            app.distributionITI.Value = 2;
            app.randomMeanITILabel = uilabel(app.IntertrialTimingsPanel);
            app.randomMeanITILabel.Text = 'Mean ITI';
            app.randomMeanITI = uieditfield(app.IntertrialTimingsPanel, 'numeric');
            app.randomMeanITI.Limits = [0 Inf];
            app.randomMeanITI.Tooltip = {'mean of random ITI distribution'};
            app.randomMeanITI.Value = 0.5;
            app.randomizeITI = uicheckbox(app.IntertrialTimingsPanel);
            app.randomizeITI.ValueChangedFcn = createCallbackFcn(app, @randomizeITIValueChanged, true);
            app.randomizeITI.Tooltip = {'randomize ITI times'};
            app.randomizeITI.Text = 'Randomize ITI';
            app.randomizeITI.Value = true;
            app.minimumITILabel = uilabel(app.IntertrialTimingsPanel);
            app.minimumITILabel.Text = 'ITI (sec)';
            app.minimumITI = uieditfield(app.IntertrialTimingsPanel, 'numeric');
            app.minimumITI.Limits = [0 Inf];
            app.minimumITI.Tooltip = {'minimum intertrial interval. If randomize selected, then the random ITI duration is added to the minimum.'};
            app.minimumITI.Value = 1;
            app.lickEncoderAvailable = uicheckbox(app.UIFigure);
            app.lickEncoderAvailable.Enable = 'off';
            app.lickEncoderAvailable.Tooltip = {'Reports whether lick encoder is available on current hardware (set in rigInfo)'};
            app.lickEncoderAvailable.Text = 'Lick Encoder Active (hardware)';
            app.lickEncoderAvailable.Value = true;
            app.useTrainingMode = uicheckbox(app.UIFigure);
            app.useTrainingMode.Text = 'Use Training Mode';
            app.useTrainingMode.Value = true;
            app.settingsNameLabel = uilabel(app.UIFigure);
            app.settingsNameLabel.Text = 'Settings Name';
            app.settingsName = uieditfield(app.UIFigure, 'text');
            app.maxTrialDurationLabel = uilabel(app.UIFigure);
            app.maxTrialDurationLabel.Text = 'Max Trial Duration';
            app.maxTrialDuration = uieditfield(app.UIFigure, 'numeric');
            app.maxTrialDuration.Limits = [1 1200];
            app.maxTrialDuration.RoundFractionalValues = 'on';
            app.maxTrialDuration.Tooltip = {'maximum number of trials (will automatically end session after this trial is reached)'};
            app.maxTrialDuration.Value = 60;
            app.maxTrialNumberLabel = uilabel(app.UIFigure);
            app.maxTrialNumberLabel.Text = 'Max Trial Number';
            app.maxTrialNumber = uieditfield(app.UIFigure, 'numeric');
            app.maxTrialNumber.Limits = [1 2000];
            app.maxTrialNumber.RoundFractionalValues = 'on';
            app.maxTrialNumber.ValueChangedFcn = createCallbackFcn(app, @maxTrialNumberValueChanged, true);
            app.maxTrialNumber.Tooltip = {'maximum number of trials (will automatically end session after this trial is reached)'};
            app.maxTrialNumber.Value = 500;
            app.sessionOffsetLabel = uilabel(app.UIFigure);
            app.sessionOffsetLabel.Text = 'Session Offset';
            app.sessionOffset = uieditfield(app.UIFigure, 'numeric');
            app.sessionOffset.Limits = [0 10000];
            app.sessionOffset.ValueChangedFcn = createCallbackFcn(app, @sessionOffsetValueChanged, true);
            app.sessionOffset.Tooltip = {'Will name each session: {AnimalName}_{SessionOffset+n}'};
            app.sessionOffset.Value = 700;
            app.animalNameLabel = uilabel(app.UIFigure);
            app.animalNameLabel.Text = 'Animal Name';
            app.animalName = uieditfield(app.UIFigure, 'text');
            app.animalName.ValueChangedFcn = createCallbackFcn(app, @animalNameValueChanged, true);
            app.animalName.Tooltip = {'determines variable names and save location throughout code'};
            app.vrDirectoryLabel = uilabel(app.UIFigure);
            app.vrDirectoryLabel.Text = 'virtual environment path';
            app.fileextensionforenvironmentsLabel = uilabel(app.UIFigure);
            app.fileextensionforenvironmentsLabel.Enable = 'off';
            app.fileextensionforenvironmentsLabel.Text = 'file extension for environments';
            app.vrExtension = uieditfield(app.UIFigure, 'text');
            app.vrExtension.ValueChangedFcn = createCallbackFcn(app, @vrExtensionValueChanged, true);
            app.vrExtension.Enable = 'off';
            app.vrExtension.Tooltip = {'file extension to use to look in virtual environment path (can include or omit leading period)'};
            app.vrExtension.Value = '.tif';
            app.updateDirectory = uibutton(app.UIFigure, 'push');
            app.updateDirectory.ButtonPushedFcn = createCallbackFcn(app, @updateDirectoryPushed, true);
            app.updateDirectory.Text = 'update path';
            app.vrDirectory = uieditfield(app.UIFigure, 'text');
            app.vrDirectory.ValueChangedFcn = createCallbackFcn(app, @vrDirectoryValueChanged, true);
            app.runExperiment = uibutton(app.UIFigure, 'push');
            app.runExperiment.ButtonPushedFcn = createCallbackFcn(app, @runExperimentButtonPushed, true);
            app.runExperiment.Text = 'run experiment';
            app.previewExperiment = uibutton(app.UIFigure, 'push');
            app.previewExperiment.ButtonPushedFcn = createCallbackFcn(app, @previewExperimentButtonPushed, true);
            app.previewExperiment.Tooltip = {'print experiment structure generated from current settings to workspace -- NOTE! if there''s any random components, it will be resampled'};
            app.previewExperiment.Text = 'preview experiment';
            app.printrigparametersButton = uibutton(app.UIFigure, 'push');
            app.printrigparametersButton.ButtonPushedFcn = createCallbackFcn(app, @printrigparametersButtonPushed, true);
            app.printrigparametersButton.Tooltip = {'upon startup, this GUI loads rig parameters. This button prints them to screen. Changing them requires hard coding the change in the relevant code'};
            app.printrigparametersButton.Text = 'print rig parameters';
            app.saveSettings = uibutton(app.UIFigure, 'push');
            app.saveSettings.ButtonPushedFcn = createCallbackFcn(app, @saveSettingsPushed, true);
            app.saveSettings.Tooltip = {'save settings to: fullfile(rigInfo.expSettingsDir, sprintf(''vrControlSettings_%s.mat'',animalName))'};
            app.saveSettings.Text = 'save settings';
            app.loadSettings = uibutton(app.UIFigure, 'push');
            app.loadSettings.ButtonPushedFcn = createCallbackFcn(app, @loadSettingsButtonPushed, true);
            app.loadSettings.Tooltip = {'load settings from: fullfile(rigInfo.expSettingsDir, sprintf(''vrControlSettings_%s.mat'',animalName))'};
            app.loadSettings.Text = 'load settings';
            app.backwardsMovementSwitch = uicheckbox(app.UIFigure);
            app.backwardsMovementSwitch.Tooltip = {'If checked, mice can only move forwards in the VR environment.'};
            app.backwardsMovementSwitch.Text = 'Prevent backwards motion';
            app.backwardsMovementSwitch.Value = true;
            app.RewardSizeEditFieldLabel = uilabel(app.UIFigure);
            app.RewardSizeEditFieldLabel.Text = 'Reward Size';
            app.rewardSize = uieditfield(app.UIFigure, 'numeric');
            app.vrenvOptions = uilistbox(app.UIFigure);
            app.vrenvOptions.Items = {};
            app.vrenvOptions.Multiselect = 'on';
            app.vrenvOptions.ValueChangedFcn = createCallbackFcn(app, @vrenvOptionsValueChanged, true);
            app.vrenvOptions.Enable = 'off';
            app.vrenvOptions.Value = {};
            app.vrenvInUse = uilistbox(app.UIFigure);
            app.vrenvInUse.Items = {};
            app.vrenvInUse.Multiselect = 'on';
            app.vrenvInUse.ValueChangedFcn = createCallbackFcn(app, @vrenvInUseValueChanged, true);
            app.vrenvInUse.Enable = 'off';
            app.vrenvInUse.Value = {};
            app.trialTypesLinkedToEnvironment = uibutton(app.UIFigure, 'state');
            app.trialTypesLinkedToEnvironment.ValueChangedFcn = createCallbackFcn(app, @trialTypesLinkedToEnvironmentValueChanged, true);
            app.trialTypesLinkedToEnvironment.Text = 'link trial types to environment (disabled)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end